classdef AnaSynch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnaSynchUIFigure              matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        AddconditionMenu              matlab.ui.container.Menu
        ImarisfilesMenu               matlab.ui.container.Menu
        DeleteMenu                    matlab.ui.container.Menu
        FileMenu_2                    matlab.ui.container.Menu
        ConditionMenu                 matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        ProcessingMenu                matlab.ui.container.Menu
        KymographMenu                 matlab.ui.container.Menu
        StatisticalanalysisMenu       matlab.ui.container.Menu
        AnaphasesspeedMenu            matlab.ui.container.Menu
        AnaphasesspeeddispersionMenu  matlab.ui.container.Menu
        KinetochoresspreadMenu        matlab.ui.container.Menu
        NonnormalizedspreadMenu       matlab.ui.container.Menu
        MetaphaseplatewidthnormalizedspreadMenu  matlab.ui.container.Menu
        MetaphaseplatewidthMenu       matlab.ui.container.Menu
        MetaphaseplatewidthtimecourseMenu  matlab.ui.container.Menu
        ConditionDropDownLabel        matlab.ui.control.Label
        ConditionDropDown             matlab.ui.control.DropDown
        ExperimentTable               matlab.ui.control.Table
        GroupSwitchLabel              matlab.ui.control.Label
        GroupSwitch                   matlab.ui.control.Switch
        UIAxes_AnaphaseA1             matlab.ui.control.UIAxes
        UIAxes_AnaphaseB              matlab.ui.control.UIAxes
        UIAxes_AnaphaseA2             matlab.ui.control.UIAxes
        UIAxes_AnaphaseA1_speed       matlab.ui.control.UIAxes
        UIAxes_AnaphaseA2_speed       matlab.ui.control.UIAxes
        UIAxes_AnaphaseB_speed        matlab.ui.control.UIAxes
        UIAxes_AnaphaseA1_delay       matlab.ui.control.UIAxes
        UIAxes_AnaphaseA2_delay       matlab.ui.control.UIAxes
        Anaphasetimecontrol           matlab.ui.container.TabGroup
        AutoonsetTab                  matlab.ui.container.Tab
        DropDown                      matlab.ui.control.DropDown
        Spinner                       matlab.ui.control.Spinner
        TypeofchangeLabel             matlab.ui.control.Label
        TypeofchangeDropDown          matlab.ui.control.DropDown
        ManualonsetTab                matlab.ui.container.Tab
        A1SpinnerLabel                matlab.ui.control.Label
        A1Spinner                     matlab.ui.control.Spinner
        A2SpinnerLabel                matlab.ui.control.Label
        A2Spinner                     matlab.ui.control.Spinner
        BSpinnerLabel                 matlab.ui.control.Label
        BSpinner                      matlab.ui.control.Spinner
        OnsettimepointshiftLabel      matlab.ui.control.Label
        DurationTab                   matlab.ui.container.Tab
        FittingwindowSpinnerLabel     matlab.ui.control.Label
        FittingwindowSpinner          matlab.ui.control.Spinner
        AnaphasetimecontrolLabel      matlab.ui.control.Label
        UIAxes_MetaphaseWidth         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        data % Description
        Experiment_idx = 1;
        Condition_idx  = 1;
        CONST
        DefaultOptions
    end
    
    methods (Access = private)
        
        function AnaphasePlot(app)
            cmap = lines(2);
            %             t0 = app.TimeafteronsetsSpinner.Value/60;
            dt = app.FittingwindowSpinner.Value/60;
            switch app.GroupSwitch.Value
                case 'Off'
                    t = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).metadata.time;
                    t = [0; cumsum(diff(t))];
                    t.Format = 'mm:ss';
                    t = minutes(t);
                    OnsetIdx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.AnaphaseOnsetIdx;
                    t_A1 = t-t(OnsetIdx);
                    OnsetIdx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.AnaphaseOnsetIdx;
                    t_A2 = t-t(OnsetIdx);
                    OnsetIdx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B.AnaphaseOnsetIdx;
                    t_B  = t-t(OnsetIdx);
                    
                    delete(app.UIAxes_AnaphaseA1.Children)
                    tidx   = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Distances.TimeIdx;
                    d_mean = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Distances.Mean;
                    d_min  = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Distances.Min;
                    d_max  = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Distances.Max;
                    d_std  = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Distances.StDev;
                    patch(app.UIAxes_AnaphaseA1,...
                        'XData',[t_A1(tidx); flipud(t_A1(tidx))],...
                        'YData',[d_max;  flipud(d_min)],...
                        'FaceColor',[0.8 0.8 0.8],...
                        'EdgeColor',[0.4 0.4 0.4],...
                        'FaceAlpha',0.2);
                    hold(app.UIAxes_AnaphaseA1,'on')
                    he = errorbar(app.UIAxes_AnaphaseA1,...
                        t_A1(tidx),d_mean,d_std);
                    he.LineStyle = 'none';
                    he.Color     = cmap(1,:);
                    he.Marker = 'o';
                    he.MarkerSize = 4;
                    he.MarkerFaceColor = cmap(1,:);
                    he.MarkerEdgeColor = [0.4 0.4 0.4];
                    if ~isempty(app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Fit)
                        tfit = t_A1(tidx);
                        %                         idxKeep = (t0<=tfit) & (tfit<=t0+dt);
                        idxKeep = (0<=tfit) & (tfit<=dt);
                        tfit = tfit(idxKeep);
                        dfit = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Fit(tfit);
                        line(app.UIAxes_AnaphaseA1,...
                            tfit,dfit,...
                            'Color',cmap(2,:),...
                            'LineWidth',2)
                    end
                    hold(app.UIAxes_AnaphaseA1,'off')
                    app.UIAxes_AnaphaseA1.XLim = t_A1([1 end]);
                    app.UIAxes_AnaphaseA1.YLim = [min(d_min(:)) max(d_max(:))];
                    
                    
                    delete(app.UIAxes_AnaphaseA2.Children)
                    tidx   = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Distances.TimeIdx;
                    d_mean = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Distances.Mean;
                    d_min  = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Distances.Min;
                    d_max  = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Distances.Max;
                    d_std  = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Distances.StDev;
                    patch(app.UIAxes_AnaphaseA2,...
                        'XData',[t_A2(tidx); flipud(t_A2(tidx))],...
                        'YData',[d_max;  flipud(d_min)],...
                        'FaceColor',[0.8 0.8 0.8],...
                        'EdgeColor',[0.4 0.4 0.4],...
                        'FaceAlpha',0.2);
                    hold(app.UIAxes_AnaphaseA2,'on')
                    he = errorbar(app.UIAxes_AnaphaseA2,...
                        t_A2(tidx),d_mean,d_std);
                    he.LineStyle = 'none';
                    he.Color     = cmap(1,:);
                    he.Marker = 'o';
                    he.MarkerSize = 4;
                    he.MarkerFaceColor = cmap(1,:);
                    he.MarkerEdgeColor = [0.4 0.4 0.4];
                    if ~isempty(app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Fit)
                        tfit = t_A2(tidx);
                        %                         idxKeep = (t0<=tfit) & (tfit<=t0+dt);
                        idxKeep = (0<=tfit) & (tfit<=dt);
                        tfit = tfit(idxKeep);
                        dfit = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Fit(tfit);
                        line(app.UIAxes_AnaphaseA2,...
                            tfit,dfit,...
                            'Color',cmap(2,:),...
                            'LineWidth',2)
                    end
                    hold(app.UIAxes_AnaphaseA2,'off')
                    app.UIAxes_AnaphaseA2.XLim = t_A2([1 end]);
                    app.UIAxes_AnaphaseA2.YLim = [min(d_min(:)) max(d_max(:))];
                    
                    
                    delete(app.UIAxes_AnaphaseB.Children)
                    tidx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B.Distances.TimeIdx;
                    d    = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B.Distances.Mean;
                    line(app.UIAxes_AnaphaseB,...
                        t_B(tidx),d,...
                        'LineStyle', 'none',...
                        'Marker', 'o',...
                        'MarkerFaceColor',cmap(1,:),...
                        'MarkerEdgeColor',[0.4 0.4 0.4],...
                        'MarkerSize',3);
                    if ~isempty(app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B.Fit)
                        tfit = t_B(tidx);
                        %                         idxKeep = (t0<=tfit) & (tfit<=t0+dt);
                        idxKeep = (0<=tfit) & (tfit<=dt);
                        tfit = tfit(idxKeep);
                        dfit = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B.Fit(tfit);
                        line(app.UIAxes_AnaphaseB,...
                            tfit,dfit,...
                            'Color',cmap(2,:),...
                            'LineWidth',2)
                    end
                    app.UIAxes_AnaphaseB.XLim = t_B([1 end]);
                    app.UIAxes_AnaphaseB.YLim = [min(d(:)) max(d(:))];
                    
                    
                    ylabel(app.UIAxes_AnaphaseA1,'Kinetochore-to-Pole distance (\mum)')
                    ylabel(app.UIAxes_AnaphaseA2,'Kinetochore-to-Pole distance (\mum)')
                    ylabel(app.UIAxes_AnaphaseB,'Pole-to-Pole distance (\mum)')
                    
                case 'On'
                    delete(app.UIAxes_AnaphaseA1.Children)
                    delete(app.UIAxes_AnaphaseA2.Children)
                    delete(app.UIAxes_AnaphaseB.Children)
                    Experiment = app.data.Condition(app.Condition_idx).Experiment;
                    for idxE = 1:length(Experiment)
                        t = Experiment(idxE).metadata.time;
                        t = [0; cumsum(diff(t))];
                        t.Format = 'mm:ss';
                        t = minutes(t);
                        
                        OnsetIdx = Experiment(idxE).A1.AnaphaseOnsetIdx;
                        t_A1 = t-t(OnsetIdx);
                        OnsetIdx = Experiment(idxE).A2.AnaphaseOnsetIdx;
                        t_A2 = t-t(OnsetIdx);
                        OnsetIdx = Experiment(idxE).B.AnaphaseOnsetIdx;
                        t_B  = t-t(OnsetIdx);
                        
                        
                        tidx   = Experiment(idxE).A1.Distances.TimeIdx;
                        d_mean = Experiment(idxE).A1.Distances.Mean;
                        M = mean(d_mean(1:OnsetIdx));
                        d_mean = d_mean/M;
                        line(app.UIAxes_AnaphaseA1,...
                            t_A1(tidx),d_mean)
                        app.UIAxes_AnaphaseA1.XLim = t_A1([1 end]);
                        app.UIAxes_AnaphaseA1.YLim = [min(d_mean(:)) max(d_mean(:))];
                        
                        
                        tidx   = Experiment(idxE).A2.Distances.TimeIdx;
                        d_mean = Experiment(idxE).A2.Distances.Mean;
                        M = mean(d_mean(1:OnsetIdx));
                        d_mean = d_mean/M;
                        line(app.UIAxes_AnaphaseA2,...
                            t_A2(tidx),d_mean)
                        app.UIAxes_AnaphaseA2.XLim = t_A2([1 end]);
                        app.UIAxes_AnaphaseA2.YLim = [min(d_mean(:)) max(d_mean(:))];
                        
                        tidx   = Experiment(idxE).B.Distances.TimeIdx;
                        d_mean = Experiment(idxE).B.Distances.Mean;
                        M = mean(d_mean(1:OnsetIdx));
                        d_mean = d_mean/M;
                        line(app.UIAxes_AnaphaseB,...
                            t_B(tidx),d_mean)
                        app.UIAxes_AnaphaseB.XLim = t_B([1 end]);
                        app.UIAxes_AnaphaseB.YLim = [min(d_mean(:)) max(d_mean(:))];
                        
                    end
                    ylabel(app.UIAxes_AnaphaseA1,'Kinetochore-to-Pole relative dist. (-)')
                    ylabel(app.UIAxes_AnaphaseA2,'Kinetochore-to-Pole relative dist. (-)')
                    ylabel(app.UIAxes_AnaphaseB,'Pole-to-Pole relative dist. (-)')
                    
            end
            app.UIAxes_AnaphaseA1.Visible = 'on';
            app.UIAxes_AnaphaseA2.Visible = 'on';
            app.UIAxes_AnaphaseB.Visible  = 'on';
            
            
        end
        
        function BuildTable(app)
            ExpNames = arrayfun(@(x) x.metadata.FileName,app.data.Condition(app.Condition_idx).Experiment,'UniformOutput',false);
            v_A1 = arrayfun(@(x) x.A1.Fit.a,app.data.Condition(app.Condition_idx).Experiment,'UniformOutput',false);
            v_A2 = arrayfun(@(x) x.A2.Fit.a,app.data.Condition(app.Condition_idx).Experiment,'UniformOutput',false);
            v_B  = arrayfun(@(x) x.B.Fit.a,app.data.Condition(app.Condition_idx).Experiment, 'UniformOutput',false);
            app.ExperimentTable.Data = [ExpNames,v_A1,v_A2,v_B];
        end
        
        function SeparationSpeedPlot(app)
            v_A1 = cell(length(app.data.Condition),1);
            v_A2 = cell(length(app.data.Condition),1);
            v_B  = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                v_A1{idxC} = cell2mat(arrayfun(@(x) x.A1.Fit.a,Experiment,'UniformOutput',false));
                v_A2{idxC} = cell2mat(arrayfun(@(x) x.A2.Fit.a,Experiment,'UniformOutput',false));
                v_B{idxC}  = cell2mat(arrayfun(@(x) x.B.Fit.a,Experiment, 'UniformOutput',false));
            end
            g_names = repelem([app.data.Condition.Name],cellfun(@length,v_A1))';
            allv_A1     = cat(1,v_A1{:});
            allv_A2     = cat(1,v_A2{:});
            allv_B      = cat(1,v_B{:});
            boxplot(app.UIAxes_AnaphaseA1_speed,allv_A1,g_names)
            boxplot(app.UIAxes_AnaphaseA2_speed,allv_A2,g_names)
            boxplot(app.UIAxes_AnaphaseB_speed,allv_B,g_names)
            hold(app.UIAxes_AnaphaseA1_speed,'on')
            hold(app.UIAxes_AnaphaseA2_speed,'on')
            hold(app.UIAxes_AnaphaseB_speed,'on')
            randA = 0.2;
            cmap = lines(length(app.data.Condition));
            for idxC = 1:length(app.data.Condition)
                
                h = plot(app.UIAxes_AnaphaseA1_speed, randA*(rand(size(v_A1{idxC}))-0.5)+idxC, v_A1{idxC},...
                    'LineStyle', 'none',...
                    'Marker', 'o',...
                    'MarkerFaceColor',cmap(idxC,:),...
                    'MarkerEdgeColor',[0.4 0.4 0.4],...
                    'MarkerSize',4);
                
                dtt = h.DataTipTemplate;
                dtt.DataTipRows(1).Label = 'Condition';
                dtt.DataTipRows(2).Label = 'Speed';
                dtt.DataTipRows(3).Label = 'Experiment';
                dtt.DataTipRows(1).Value = repmat(app.data.Condition(idxC).Name, size(ExpNames{idxC}));
                dtt.DataTipRows(2).Value = cellfun(@(x) num2str(x,'%4.3f \\mum\\cdotmin^{-1}'),num2cell(h.YData),'UniformOutput',false);% repmat({'x^2'},size(g_names));%  cellfun(@(x) ['$' x  'x^2$'], cellfun(@(x) num2str(x,'%4.3f'),num2cell(h.YData),'UniformOutput',false),'UniformOutput',false );%h.YData;
                dtt.DataTipRows(3).Value =  cellfun(@(x) replace(x,'_','\_') , ExpNames{idxC} ,    'UniformOutput' ,    false);
                %                dtt.DataTipRows(2).Format = '%.3f $\\mu mmin^{-1}$';
                dtt.Interpreter = 'tex';
                
                h =plot(app.UIAxes_AnaphaseA2_speed, randA*(rand(size(v_A2{idxC}))-0.5)+idxC, v_A2{idxC},...
                    'LineStyle', 'none',...
                    'Marker', 'o',...
                    'MarkerFaceColor',cmap(idxC,:),...
                    'MarkerEdgeColor',[0.4 0.4 0.4],...
                    'MarkerSize',4);
                dtt = h.DataTipTemplate;
                dtt.DataTipRows(1).Label = 'Condition';
                dtt.DataTipRows(2).Label = 'Speed';
                dtt.DataTipRows(3).Label = 'Experiment';
                dtt.DataTipRows(1).Value = repmat(app.data.Condition(idxC).Name, size(ExpNames{idxC}));
                dtt.DataTipRows(2).Value = cellfun(@(x) num2str(x,'%4.3f \\mum\\cdotmin^{-1}'),num2cell(h.YData),'UniformOutput',false);
                dtt.DataTipRows(3).Value =  cellfun(@(x) replace(x,'_','\_') , ExpNames{idxC} ,    'UniformOutput' ,    false);
                dtt.Interpreter = 'tex';
                
                h = plot(app.UIAxes_AnaphaseB_speed, randA*(rand(size(v_B{idxC}))-0.5)+idxC, v_B{idxC},...
                    'LineStyle', 'none',...
                    'Marker', 'o',...
                    'MarkerFaceColor',cmap(idxC,:),...
                    'MarkerEdgeColor',[0.4 0.4 0.4],...
                    'MarkerSize',4);
                dtt = h.DataTipTemplate;
                dtt.DataTipRows(1).Label = 'Condition';
                dtt.DataTipRows(2).Label = 'Speed';
                dtt.DataTipRows(3).Label = 'Experiment';
                dtt.DataTipRows(1).Value = repmat(app.data.Condition(idxC).Name, size(ExpNames{idxC}));
                dtt.DataTipRows(2).Value = cellfun(@(x) num2str(x,'%4.3f \\mum\\cdotmin^{-1}'),num2cell(h.YData),'UniformOutput',false);
                dtt.DataTipRows(3).Value =  cellfun(@(x) replace(x,'_','\_') , ExpNames{idxC} ,    'UniformOutput' ,    false);
                dtt.Interpreter = 'tex';
                
            end
            hold(app.UIAxes_AnaphaseA1_speed,'off')
            hold(app.UIAxes_AnaphaseA2_speed,'off')
            hold(app.UIAxes_AnaphaseB_speed,'off')
            app.UIAxes_AnaphaseA1_speed.Visible = 'on';
            app.UIAxes_AnaphaseA2_speed.Visible = 'on';
            app.UIAxes_AnaphaseB_speed.Visible = 'on';
            
        end
        
        function Stats = StatTest(app,Meas,Conditions,alpha)
            p_normality = zeros(length(Conditions),1);
            h_normality = false(length(Conditions),1);
            m   = zeros(length(Conditions),1);
            s   = zeros(length(Conditions),1);
            sem = zeros(length(Conditions),1);
            N   = zeros(length(Conditions),1);
            for idxC = 1:length(Conditions)
                idxKeep = Meas.Condition == Conditions(idxC);
                [h_normality(idxC),p_normality(idxC)] = lillietest(Meas{idxKeep,2},'Alpha',alpha,...
                    'Distribution','normal','MCTol',0.01);
                m(idxC)   = mean(Meas{idxKeep,2});
                s(idxC)   = std(Meas{idxKeep,2});
                N(idxC)   = sum(idxKeep);
                sem(idxC) = s(idxC)*N(idxC)^-0.5;
            end
            
            Stats.Normality.p = p_normality;
            Stats.m   = m;
            Stats.s   = s;
            Stats.sem = sem;
            Stats.N   = N;
            
            if length(Conditions) == 2
                x = Meas{Meas.Condition == Conditions(1),2};
                y = Meas{Meas.Condition == Conditions(2),2};
                if all(~h_normality)%normal dist.
                    [h,p,ci,stats] = vartest2(x,y,'Alpha',alpha,'Tail','both');
                    if h
                        Vartype = 'equal';
                    else
                        Vartype = 'unequal';
                    end
                    [h,p,ci,stats] = ttest2(x,y,'Alpha',alpha,'Tail','both','Vartype',Vartype);
                    Stats.TestName = {'t-test ' Vartype ' variance'};
                    Stats.p = p;
                    Stats.stats = stats;
                else
                    [p,h,stats] = ranksum(x,y,'Alpha',alpha,'Tail','both');
                    Stats.TestName = {'Mann-Whitney U-test'};
                    Stats.p = p;
                    Stats.stats = stats;
                end
            elseif length(Conditions) > 2
                if all(~h_normality)%normal dist.
                    [p,tbl,stats] = anova1(Meas{:,2},Meas.Condition,'off');
                    Stats.TestName = {'One-way analysis of variance'};
                    Stats.p = p;
                    Stats.stats = stats;
                    Stats.tbl   = tbl;
                else
                    [p,tbl,stats] = kruskalwallis(Meas{:,2},Meas.Condition,'off');
                    Stats.TestName = {'Kruskal-Wallis test'};
                    Stats.p = p;
                    Stats.stats = stats;
                    Stats.tbl   = tbl;
                end
                if Stats.p<=alpha
                    [c,m,h,gnames] = multcompare(stats,'Alpha',alpha,'Display','off');
                    Stats.multi.c = c;
                    %                     Stats.multi.gnames;
                end
            end
        end
        
        function KinetochoreSpreadPlot(app)
            Kshift_A1 = cell(length(app.data.Condition),1);
            Kshift_A2 = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                Kshift_A1{idxC} = arrayfun(@(x) x.A1.Kshift,Experiment,'UniformOutput',true);
                Kshift_A2{idxC} = arrayfun(@(x) x.A2.Kshift,Experiment,'UniformOutput',true);
            end
            g_names = repelem([app.data.Condition.Name],cellfun(@length,Kshift_A1))';
            alld_A1     = cat(1,Kshift_A1{:});
            alld_A2     = cat(1,Kshift_A2{:});
            boxplot(app.UIAxes_AnaphaseA1_delay,alld_A1,g_names)
            boxplot(app.UIAxes_AnaphaseA2_delay,alld_A2,g_names)
            
            hold(app.UIAxes_AnaphaseA1_delay,'on')
            hold(app.UIAxes_AnaphaseA2_delay,'on')
            randA = 0.2;
            cmap = lines(length(app.data.Condition));
            for idxC = 1:length(app.data.Condition)
                
                h = plot(app.UIAxes_AnaphaseA1_delay, randA*(rand(size(Kshift_A1{idxC}))-0.5)+idxC, Kshift_A1{idxC},...
                    'LineStyle', 'none',...
                    'Marker', 'o',...
                    'MarkerFaceColor',cmap(idxC,:),...
                    'MarkerEdgeColor',[0.4 0.4 0.4],...
                    'MarkerSize',4);
                
                dtt = h.DataTipTemplate;
                dtt.DataTipRows(1).Label = 'Condition';
                dtt.DataTipRows(2).Label = 'Speed';
                dtt.DataTipRows(3).Label = 'Experiment';
                dtt.DataTipRows(1).Value = repmat(app.data.Condition(idxC).Name, size(ExpNames{idxC}));
                dtt.DataTipRows(2).Value = cellfun(@(x) num2str(x,'%4.3f'),num2cell(h.YData),'UniformOutput',false);% repmat({'x^2'},size(g_names));%  cellfun(@(x) ['$' x  'x^2$'], cellfun(@(x) num2str(x,'%4.3f'),num2cell(h.YData),'UniformOutput',false),'UniformOutput',false );%h.YData;
                dtt.DataTipRows(3).Value =  cellfun(@(x) replace(x,'_','\_') , ExpNames{idxC} ,    'UniformOutput' ,    false);
                dtt.Interpreter = 'tex';
                
                h =plot(app.UIAxes_AnaphaseA2_delay, randA*(rand(size(Kshift_A2{idxC}))-0.5)+idxC, Kshift_A2{idxC},...
                    'LineStyle', 'none',...
                    'Marker', 'o',...
                    'MarkerFaceColor',cmap(idxC,:),...
                    'MarkerEdgeColor',[0.4 0.4 0.4],...
                    'MarkerSize',4);
                dtt = h.DataTipTemplate;
                dtt.DataTipRows(1).Label = 'Condition';
                dtt.DataTipRows(2).Label = 'Speed';
                dtt.DataTipRows(3).Label = 'Experiment';
                dtt.DataTipRows(1).Value = repmat(app.data.Condition(idxC).Name, size(ExpNames{idxC}));
                dtt.DataTipRows(2).Value = cellfun(@(x) num2str(x,'%4.3f'),num2cell(h.YData),'UniformOutput',false);
                dtt.DataTipRows(3).Value = cellfun(@(x) replace(x,'_','\_') , ExpNames{idxC} ,    'UniformOutput' ,    false);
                dtt.Interpreter = 'tex';
                
            end
            hold(app.UIAxes_AnaphaseA1_delay,'off')
            hold(app.UIAxes_AnaphaseA2_delay,'off')
            app.UIAxes_AnaphaseA1_delay.Visible = 'on';
            app.UIAxes_AnaphaseA2_delay.Visible = 'on';
            
            
        end
        
        function KinetochoreSpread(app)
            %             t0 = app.TimeafteronsetsSpinner.Value/60;
            dt = app.FittingwindowSpinner.Value/60;
            
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                for idxE = 1:length(Experiment)
                    t_full = Experiment(idxE).metadata.time;
                    t_full = [0; cumsum(diff(t_full))];
                    t_full.Format = 'mm:ss';
                    t_full = minutes(t_full);
                    AnaphaseOnsetIdx = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    t_A1 = t_full-t_full(AnaphaseOnsetIdx);
                    AnaphaseOnsetIdx = Experiment(idxE).A2.AnaphaseOnsetIdx;
                    t_A2 = t_full-t_full(AnaphaseOnsetIdx);
                    AnaphaseOnsetIdx = Experiment(idxE).B.AnaphaseOnsetIdx;
                    t_B = t_full-t_full(AnaphaseOnsetIdx);
                    
                    tidx =  Experiment(idxE).A1.Distances.TimeIdx;
                    t = t_A1(tidx);
                    %                     idxKeep = (t0<=t) & (t<=t0+dt);
                    idxKeep = (0<=t) & (t<=dt);
                    s = Experiment(idxE).A1.Zenith.Max(idxKeep)-Experiment(idxE).A1.Zenith.Min(idxKeep);
                    %                     s = Experiment(idxE).A1.Zenith.StDev(idxKeep);
                    %                      s = Experiment(idxE).A1.Zenith.Skewness(idxKeep);
                    [~,pos] = max(abs(s));
                    s = s(pos);
                    app.data.Condition(idxC).Experiment(idxE).A1.Kshift = s;
                    
                    %                     Experiment(idxE).metadata.FileName
                    
                    tidx =  Experiment(idxE).A2.Distances.TimeIdx;
                    t = t_A2(tidx);
                    %                     idxKeep = (t0<=t) & (t<=t0+dt);
                    idxKeep = (0<=t) & (t<=dt);
                    
                    s = Experiment(idxE).A2.Zenith.Max(idxKeep)-Experiment(idxE).A2.Zenith.Min(idxKeep);
                    %                     s = Experiment(idxE).A2.Zenith.StDev(idxKeep);
                    %                     s = Experiment(idxE).A2.Zenith.Skewness(idxKeep);
                    [~,pos] = max(abs(s));
                    s = s(pos);
                    app.data.Condition(idxC).Experiment(idxE).A2.Kshift = s;
                    
                end
            end
            
        end
        
        function InitializeDisplay(app)
            app.ExperimentTable.Data = [];
            app.ConditionDropDown.Items = {};
            app.ConditionDropDown.Value = {};
            app.UIAxes_AnaphaseA1.Visible = 'off';
            app.UIAxes_AnaphaseA2.Visible = 'off';
            app.UIAxes_AnaphaseB.Visible = 'off';
            delete(app.UIAxes_AnaphaseA1.Children)
            delete(app.UIAxes_AnaphaseA2.Children)
            delete(app.UIAxes_AnaphaseB.Children)
            app.UIAxes_AnaphaseA1_speed.Visible = 'off';
            app.UIAxes_AnaphaseA2_speed.Visible = 'off';
            app.UIAxes_AnaphaseB_speed.Visible = 'off';
            app.UIAxes_MetaphaseWidth.Visible = 'off';
            delete(app.UIAxes_MetaphaseWidth.Children)
            delete(app.UIAxes_AnaphaseA1_speed.Children)
            delete(app.UIAxes_AnaphaseA2_speed.Children)
            delete(app.UIAxes_AnaphaseB_speed.Children)
            app.UIAxes_AnaphaseA1_delay.Visible = 'off';
            app.UIAxes_AnaphaseA2_delay.Visible = 'off';
            delete(app.UIAxes_AnaphaseA1_delay.Children)
            delete(app.UIAxes_AnaphaseA2_delay.Children)
            app.Anaphasetimecontrol.Visible = 'off';
            app.AnaphasetimecontrolLabel.Visible = 'off';
            
            app.TypeofchangeDropDown.Value = app.DefaultOptions.AnaphaseOnsetParam.Type;
            app.DropDown.Value = app.DefaultOptions.AnaphaseOnsetParam.Measure;
            app.Spinner.Value = app.DefaultOptions.AnaphaseOnsetParam.Value;
            app.A1Spinner.Tag = 'A1ManOnset';
            app.A2Spinner.Tag = 'A2ManOnset';
            app.BSpinner.Tag  = 'BManOnset';
        end
        
        
        function isPoolPole = Pole1vsPole2(app,Meas,alpha,figtitle,Measlbl,questlbl)
            try
                close(findall(groot,'type','figure','Name',figtitle))
            end
            fig_A1A2 = uifigure('Name',figtitle);
            
            Conditions = categorical([app.data.Condition.Name]);
            isPoolable = false(size(Conditions));
            %             gl = uigridlayout(fig_A1A2,[2,2*length(Conditions)]);
            gl = tiledlayout(fig_A1A2,2,2*length(Conditions));
            ax = gobjects(2,2*length(Conditions));
            cmap = lines(length(app.data.Condition));
            for idxTile = 1:2:2*length(Conditions)
                idxCond = 1+(idxTile-1)/2;
                
                %____
                ax(1,idxTile) =nexttile(gl,idxTile,[1 1]);
                
                %                 ax(1,idxTile) = uiaxes(gl);
                %                 ax(1,idxTile).Layout.Row = 1;
                %                 ax(1,idxTile).Layout.Column = idxTile;
                
                idxKeepM = Meas.Condition == Conditions(idxCond);
                
                htmp = mynormplot( ax(1,idxTile),Meas{idxKeepM,end-1},Meas.Experience(idxKeepM));
                
                [h_normality(1),p_normality(1)] = lillietest(Meas{idxKeepM,end-1},'Alpha',alpha,...
                    'Distribution','normal','MCTol',0.01);
                
                htmp(1).Marker = 'o';
                htmp(1).MarkerFaceColor = cmap(idxCond,:);
                htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                htmp(1).MarkerSize = 4;
                htmp(2).Color = cmap(idxCond,:);
                htmp(3).Color = cmap(idxCond,:);
                ax(1,idxTile).XLabel.String = Measlbl;
                ax(1,idxTile).Title.String  = {[char(Conditions(idxCond)) ' - pole 1'];...
                    ['Lilliefors test for normal distribution p=' num2str(p_normality(1))]};
                ax(idxTile).Box = 'on';
                
                %____
                ax(1,idxTile+1) =nexttile(gl,idxTile+1,[1 1]);
                %                 ax(1,idxTile+1) = uiaxes(gl);
                %                 ax(1,idxTile+1).Layout.Row = 1;
                %                 ax(1,idxTile+1).Layout.Column = idxTile+1;
                
                idxKeepM = Meas.Condition == Conditions(idxCond);
                
                htmp = mynormplot( ax(1,idxTile+1),Meas{idxKeepM,end},Meas.Experience(idxKeepM));
                
                [h_normality(2),p_normality(2)] = lillietest(Meas{idxKeepM,end},'Alpha',alpha,...
                    'Distribution','normal','MCTol',0.01);
                
                
                htmp(1).Marker = 'o';
                htmp(1).MarkerFaceColor = cmap(idxCond,:);
                htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                htmp(1).MarkerSize = 4;
                htmp(2).Color = cmap(idxCond,:);
                htmp(3).Color = cmap(idxCond,:);
                ax(1,idxTile+1).XLabel.String = Measlbl;
                ax(1,idxTile+1).Title.String  = {[char(Conditions(idxCond)) ' - pole 2'];...
                    ['Lilliefors test for normal distribution p=' num2str(p_normality(2))]};
                ax(1,idxTile+1).Box = 'on';
                
                %____
                ax(2,idxTile) =nexttile(gl,2*length(Conditions)+idxTile,[1 2]);
                %                 ax(2,idxTile) = uiaxes(gl);
                %                 ax(2,idxTile).Layout.Row = 2;
                %                 ax(2,idxTile).Layout.Column = [idxTile idxTile+1];
                
                idxKeepM = find(idxKeepM);
                hold(ax(2,idxTile),'on')
                for idxK = 1:length(idxKeepM)
                    plot(ax(2,idxTile),...
                        [1 2],...
                        [Meas{(idxKeepM(idxK)),end-1},Meas{(idxKeepM(idxK)),end}],...
                        'Marker','o',...
                        'MarkerFaceColor',cmap(idxCond,:),...
                        'MarkerEdgeColor',[0.4 0.4 0.4],...
                        'MarkerSize',4,...
                        'Color',cmap(idxCond,:),...
                        'LineStyle','--')
                    
                end
                if ~h_normality(1)&&~h_normality(2)
                    [h,p] = ttest(Meas{idxKeepM,end-1},Meas{idxKeepM,end});
                    txt = ['Paired t-test, p=' num2str(p)];
                    
                else
                    [p,h] = signrank(Meas{idxKeepM,end-1},Meas{idxKeepM,end});
                    txt = ['Wilcoxon signed rank test, p=' num2str(p)];
                end
                isPoolable(idxCond) = ~h;
                
                hold(ax(2,idxTile),'off')
                ax(2,idxTile).XLim = [0.5 2.5];
                ax(2,idxTile).Box  = 'on';
                ax(2,idxTile).XTick = [1 2];
                ax(2,idxTile).XTickLabel = {'Pole1','Pole2'};
                ax(2,idxTile).Title.String = txt;
            end
            isPoolPole = uiconfirm(fig_A1A2,['The poles can be pooled. Do you want to fuse the ' questlbl ' measurements?'],'Pool the poles','Icon','question');
            switch isPoolPole
                case 'OK'
                    isPoolPole = true;
                case 'Cancel'
                    isPoolPole = false;
            end
            
        end
        
        function MetaphaseWidth(app)
            dt = -30;%seconds before anaphase onset
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                for idxE = 1:length(Experiment)
                    t_full = Experiment(idxE).metadata.time;
                    t_full = [0; cumsum(diff(t_full))];
                    t_full.Format = 'mm:ss';
                    t_full = minutes(t_full);
                    AnaphaseOnsetIdxA1 = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    AnaphaseOnsetIdxA2 = Experiment(idxE).A2.AnaphaseOnsetIdx;
                    AnaphaseOnsetIdx = min(AnaphaseOnsetIdxA1,AnaphaseOnsetIdxA2);
                    t = t_full-t_full(AnaphaseOnsetIdx);
                    
                    idxKeep = (dt<=t) & (t<=0);
                    
                    w = mean(Experiment(idxE).MetaphasePlate.data.StDev(idxKeep));
                    
                    app.data.Condition(idxC).Experiment(idxE).MetaphasePlate.MetaWidth = w;
                    
                    
                end
            end
        end
        
        function MetaphaseWidthPlot(app)
            
            MetaWidth = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                MetaWidth{idxC} = arrayfun(@(x) x.MetaphasePlate.MetaWidth,Experiment,'UniformOutput',true);
            end
            g_names = repelem([app.data.Condition.Name],cellfun(@length,MetaWidth))';
            allWidth     = cat(1,MetaWidth{:});
            
            boxplot(app.UIAxes_MetaphaseWidth,allWidth,g_names)
            
            hold(app.UIAxes_MetaphaseWidth,'on')
            
            randA = 0.2;
            cmap = lines(length(app.data.Condition));
            for idxC = 1:length(app.data.Condition)
                
                h = plot(app.UIAxes_MetaphaseWidth, randA*(rand(size(MetaWidth{idxC}))-0.5)+idxC, MetaWidth{idxC},...
                    'LineStyle', 'none',...
                    'Marker', 'o',...
                    'MarkerFaceColor',cmap(idxC,:),...
                    'MarkerEdgeColor',[0.4 0.4 0.4],...
                    'MarkerSize',4);
                
                dtt = h.DataTipTemplate;
                dtt.DataTipRows(1).Label = 'Condition';
                dtt.DataTipRows(2).Label = 'Plate width';
                dtt.DataTipRows(3).Label = 'Experiment';
                dtt.DataTipRows(1).Value = repmat(app.data.Condition(idxC).Name, size(ExpNames{idxC}));
                dtt.DataTipRows(2).Value = cellfun(@(x) num2str(x,'%4.3f'),num2cell(h.YData),'UniformOutput',false);% repmat({'x^2'},size(g_names));%  cellfun(@(x) ['$' x  'x^2$'], cellfun(@(x) num2str(x,'%4.3f'),num2cell(h.YData),'UniformOutput',false),'UniformOutput',false );%h.YData;
                dtt.DataTipRows(3).Value =  cellfun(@(x) replace(x,'_','\_') , ExpNames{idxC} ,    'UniformOutput' ,    false);
                dtt.Interpreter = 'tex';


            end
            hold(app.UIAxes_MetaphaseWidth,'off')
            app.UIAxes_MetaphaseWidth.Visible = 'on';
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AnaSynchUIFigure.Visible = 'off';
            
            cd(fileparts(which('AnaSynch.mlapp')))
            addpath(genpath('mfiles'))
            DefaultOptions = load("mfiles\DefaultOptions.mat");
            app.DefaultOptions = DefaultOptions.DefaultOptions;
            movegui(app.AnaSynchUIFigure,'center')
            InitializeDisplay(app)
            app.AnaSynchUIFigure.Visible = 'on';
            
            
            %             dbstop if caught error
        end

        % Menu selected function: ImarisfilesMenu
        function ImarisfilesMenuSelected(app, event)
            [ImageFiles,ImagePath] = uigetfile({'*.ims', 'Imaris files (*.ims)'},...
                'Select some Imaris files',...
                app.DefaultOptions.LastFolderPath,...
                'MultiSelect', 'on');
            if isnumeric(ImagePath)
                return
            end
            
            if ischar(ImageFiles)
                ImageFiles = {ImageFiles};
            end
            
            app.DefaultOptions.LastFolderPath = ImagePath;
            DefaultOptions = app.DefaultOptions;
            save("mfiles\DefaultOptions.mat",'DefaultOptions')
            
            
            [~,ConditionName]  = fileparts(ImagePath(1:end-1));
            
            ConditionName = inputdlg('Confirm the condition name:','',1,{ConditionName});
            
            if isempty(ConditionName)
                return
            else
                if isempty(ConditionName{:})
                    return
                end
            end
            
            isExistingCondition = false;
            if isfield(app.data,'Condition')
                app.Condition_idx = length(app.data.Condition)+1;
                if ismember(ConditionName,[app.data.Condition.Name])
                    selection = uiconfirm(app.AnaSynchUIFigure,[ConditionName{:} 'already exist'],...
                        'Existing condition !',...
                        'Options',{'Add','Abort'}, ...
                        'Icon','warning');
                    if strcmp(selection,'Abort')
                        return
                    else
                        isExistingCondition = true;
                        app.Condition_idx = find(ismember([app.data.Condition.Name],ConditionName));
                        ExpNames = arrayfun(@(x) x.metadata.FileName,app.data.Condition(app.Condition_idx).Experiment,'UniformOutput',false);
                        tmp = regexp(ImageFiles,'.ims','split');
                        tmp = cat(1,tmp{:});
                        tmp = tmp(:,1);
                        ImageFiles = ImageFiles(~ismember(tmp,ExpNames));
                    end
                end
            else
                app.Condition_idx = 1;
            end
            
            
            Experiment = struct('metadata',cell(length(ImageFiles),1),...
                'Tracks',cell(length(ImageFiles),1),...
                'A1',cell(length(ImageFiles),1),...
                'A2',cell(length(ImageFiles),1),...
                'B',cell(length(ImageFiles),1));
            
            idxKill = false(length(ImageFiles),1);
            d = uiprogressdlg(app.AnaSynchUIFigure,'Title','Please Wait',...
                'Message','Loading and preprocessing your data','Cancelable','on');
            for idxF = 1:length(ImageFiles)
                d.Value = idxF/length(ImageFiles);
                if d.CancelRequested
                    return
                end
                
                [metadata, spots] = imsread(fullfile(ImagePath,ImageFiles{idxF}));
                if isempty(spots)
                    selection = uiconfirm(app.AnaSynchUIFigure,[ImageFiles{idxF} ' has no spot!'],...
                        'Invalid File',...
                        'Options',{'Abort','Discard'}, ...
                        'Icon','error');
                    if strcmp(selection,'Abort')
                        return
                    else
                        idxKill(idxF) = true;
                    end
                else
                    Names = [spots.Name];
                    idxK1 = ismember(Names,'K1');
                    idxK2 = ismember(Names,'K2');
                    idxP1 = ismember(Names,'P1');
                    idxP2 = ismember(Names,'P2');
                    
                    if ~all(sum([idxK1' idxK2' idxP1' idxP2']) == ones(1,4))
                        selection = uiconfirm(app.AnaSynchUIFigure,[ImageFiles{idxF} ' has spot(s) with invalid name(s)!'],...
                            'Invalid File',...
                            'Options',{'Abort','Discard'}, ...
                            'Icon','error');
                        if strcmp(selection,'Abort')
                            return
                        else
                            idxKill(idxF) = true;
                        end
                    else
                        Tracks.Kinetochore1 = spots(idxK1).Tracks;
                        Tracks.Kinetochore2 = spots(idxK2).Tracks;
                        Tracks.Pole1 = spots(idxP1).Tracks;
                        Tracks.Pole2 = spots(idxP2).Tracks;
                        [AnaphaseA1,AnaphaseA2,AnaphaseB,ProbA1,ProbA2,ZenithA1,ZenithA2,MetaphasePlate] = AnaphasePerCell(Tracks);
                        
                        AnaphaseA1OnsetIdx = find(ischange(AnaphaseA1.Mean,app.DefaultOptions.AnaphaseOnsetParam.Type,app.DefaultOptions.AnaphaseOnsetParam.Measure,app.DefaultOptions.AnaphaseOnsetParam.Value),1);
                        AnaphaseA2OnsetIdx = find(ischange(AnaphaseA2.Mean,app.DefaultOptions.AnaphaseOnsetParam.Type,app.DefaultOptions.AnaphaseOnsetParam.Measure,app.DefaultOptions.AnaphaseOnsetParam.Value),1);
                        AnaphaseBOnsetIdx  = find(ischange(AnaphaseB.Mean,app.DefaultOptions.AnaphaseOnsetParam.Type,app.DefaultOptions.AnaphaseOnsetParam.Measure,app.DefaultOptions.AnaphaseOnsetParam.Value),1);
                        
                        A1.Distances = AnaphaseA1;
                        A1.Prob      = ProbA1;
                        A1.Zenith    = ZenithA1;
                        A2.Distances = AnaphaseA2;
                        A2.Prob      = ProbA2;
                        A2.Zenith    = ZenithA2;
                        B.Distances  = AnaphaseB;
                        
                        Experiment(idxF).metadata = metadata;
                        Experiment(idxF).Tracks   = Tracks;
                        Experiment(idxF).A1       = A1;
                        Experiment(idxF).A2       = A2;
                        Experiment(idxF).B         = B;
                        Experiment(idxF).A1.AnaphaseOnsetIdx = AnaphaseA1OnsetIdx;
                        Experiment(idxF).A2.AnaphaseOnsetIdx = AnaphaseA2OnsetIdx;
                        Experiment(idxF).B.AnaphaseOnsetIdx  = AnaphaseBOnsetIdx;
                        Experiment(idxF).MetaphasePlate.data = MetaphasePlate;
                        
                        
                    end
                end
            end
            if all(idxKill)
                return
            end
            Experiment(idxKill) = [];
            
            app.Experiment_idx = 1;
            app.data.Condition(app.Condition_idx).Name = ConditionName;
            app.data.Condition(app.Condition_idx).ImagePath = ImagePath;
            
            if isExistingCondition
                app.data.Condition(app.Condition_idx).Experiment = [app.data.Condition(app.Condition_idx).Experiment;Experiment];
            else
                app.data.Condition(app.Condition_idx).Experiment = Experiment;
            end
            
            
            app.ConditionDropDown.Items = [app.data.Condition.Name];
            app.ConditionDropDown.Value = app.ConditionDropDown.Items(app.Condition_idx);
            
            ResT_min = 100000;
            Duration_max = 0;
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                curr_ResT = arrayfun(@(x) x.metadata.ResT,Experiment);
                curr_DimT = arrayfun(@(x) x.metadata.DimT,Experiment);
                ResT_min = min([ResT_min;curr_ResT]);
                Duration_max = max([Duration_max;(curr_DimT-1).*curr_ResT]);
            end
            Duration_max = ceil(seconds(Duration_max));
            ResT_min = floor(seconds(ResT_min));
            app.CONST.Duration_max = Duration_max;
            app.CONST.ResT_min = ResT_min;
            
            app.FittingwindowSpinner.Limits = [3*app.CONST.ResT_min app.CONST.Duration_max];
            app.FittingwindowSpinner.Step   = app.CONST.ResT_min;
            
            close(d)
            app.Anaphasetimecontrol.Visible = 'on';
            app.AnaphasetimecontrolLabel.Visible = 'on';
            AnaphaseFittingAll(app)
            KinetochoreSpread(app)
            MetaphaseWidth(app)
            BuildTable(app)
            AnaphasePlot(app)
            SeparationSpeedPlot(app)
            KinetochoreSpreadPlot(app)
            MetaphaseWidthPlot(app)
        end

        % Menu selected function: OpenMenu
        function Open(app, event)
            [Savefile,Folder]   =  uigetfile({'*.mat', 'MATLAB File (*.mat)'},...
                'Select some Imaris files',...
                app.DefaultOptions.LastFolderPath,...
                'MultiSelect', 'off');
            
            if isnumeric(Folder)
                return
            end
            wb = uiprogressdlg(app.AnaSynchUIFigure,'Title','Wait while opening your data',...
                'Indeterminate','on');
            data = load(fullfile(Folder,Savefile));
            app.data  = data.data;
            app.CONST = data.CONST;
            
            app.Condition_idx  = 1;
            app.Experiment_idx = 1;
            
            app.ConditionDropDown.Items = [app.data.Condition.Name];
            app.ConditionDropDown.Value = app.ConditionDropDown.Items(app.Condition_idx);
            app.FittingwindowSpinner.Limits = [3*app.CONST.ResT_min app.CONST.Duration_max];
            app.FittingwindowSpinner.Step   = app.CONST.ResT_min;
            app.FittingwindowSpinner.Value     = app.CONST.dt;
            app.TypeofchangeDropDown.Value = app.CONST.TypeOfChange;
            app.DropDown.Value = app.CONST.MaxNumChanges_Threshold;
            app.Spinner.Value = app.CONST.Threshold;
            
            BuildTable(app)
            AnaphasePlot(app)
            SeparationSpeedPlot(app)
            KinetochoreSpreadPlot(app)
            MetaphaseWidthPlot(app)
            
            app.Anaphasetimecontrol.Visible = 'on';
            app.AnaphasetimecontrolLabel.Visible = 'on';
            app.DefaultOptions.LastFolderPath = Folder;
            DefaultOptions = app.DefaultOptions;
            save("mfiles\DefaultOptions.mat",'DefaultOptions')
            
            close(wb)
            
            
        end

        % Menu selected function: SaveMenu
        function Save(app, event)
            [Savefile,Folder] = uiputfile({'*.mat' 'MATLAB File (*.mat)'},'Save your workspace',app.DefaultOptions.LastFolderPath);
            if isnumeric(Folder)
                return
            end
            wb = uiprogressdlg(app.AnaSynchUIFigure,'Title','Wait while saving your data',...
                'Indeterminate','on');


            FullPath = fullfile(Folder,Savefile);
            data = app.data;
            CONST = app.CONST;
            CONST.dt = app.FittingwindowSpinner.Value;
            
            CONST.TypeOfChange  = app.TypeofchangeDropDown.Value;
            CONST.MaxNumChanges_Threshold = app.DropDown.Value;
            CONST.Threshold= app.Spinner.Value;
            
            save(FullPath,'data','CONST','-v7.3')
            
            app.DefaultOptions.LastFolderPath = Folder;
            DefaultOptions = app.DefaultOptions;
            save("mfiles\DefaultOptions.mat",'DefaultOptions')
            
            close(wb)


        end

        % Menu selected function: ConditionMenu, FileMenu_2
        function Remove(app, event)
            
            
            switch event.Source.Text
                
                
                case 'File'
                    ExpNames = arrayfun(@(x) x.metadata.FileName,app.data.Condition(app.Condition_idx).Experiment,'UniformOutput',false);
                    [indx,tf] = listdlg('ListString',ExpNames);
                    if ~tf
                        return
                    end
                    if all(ismember([1:length(app.data.Condition(app.Condition_idx).Experiment)],indx))
                        app.data.Condition(app.Condition_idx) = [];
                        app.Condition_idx = 1;
                        app.Experiment_idx = 1;
                        if ~isempty(app.data.Condition)
                            app.ConditionDropDown.Items = [app.data.Condition.Name];
                            app.ConditionDropDown.Value = app.ConditionDropDown.Items(app.Condition_idx);
                        else
                            app.data = [];
                            InitializeDisplay(app)
                        end
                    else
                        app.data.Condition(app.Condition_idx).Experiment(indx) = [];
                        app.Experiment_idx = 1;
                    end
                    
                case 'Condition'
                    [indx,tf] = listdlg('ListString',[app.data.Condition.Name]);
                    if ~tf
                        return
                    end
                    app.data.Condition(indx) = [];
                    
                    app.Condition_idx = 1;
                    app.Experiment_idx = 1;
                    if ~isempty(app.data.Condition)
                        app.ConditionDropDown.Items = [app.data.Condition.Name];
                        app.ConditionDropDown.Value = app.ConditionDropDown.Items(app.Condition_idx);
                    else
                        app.data = [];
                        InitializeDisplay(app)
                    end
                    
            end
            if ~isempty(app.data)
                BuildTable(app)
                AnaphasePlot(app)
                SeparationSpeedPlot(app)
                KinetochoreSpreadPlot(app)
                MetaphaseWidthPlot(app)
            end


        end

        % Cell selection callback: ExperimentTable
        function ExperimentTableCellSelection(app, event)
            app.Experiment_idx = event.Indices(end,1);
            AnaphasePlot(app)
        end

        % Value changed function: GroupSwitch
        function GroupExp(app, event)
            AnaphasePlot(app)
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            app.Condition_idx = find(ismember(app.ConditionDropDown.Items,app.ConditionDropDown.Value));
            app.Experiment_idx = 1;
            BuildTable(app)
            AnaphasePlot(app)
        end

        % Value changed function: FittingwindowSpinner
        function AnaphaseFittingAll(app, event)
            
            
            %             t0 = app.TimeafteronsetsSpinner.Value/60;
            dt = app.FittingwindowSpinner.Value/60;
            
            wb = uiprogressdlg(app.AnaSynchUIFigure,'Title','Anaphase fitting...',...
                'Indeterminate','on');
            
            %             Fitmodel = fittype( @(a,b,x) a*x+b,'independent','x','coefficients',{'a','b'} );
            %             options = fitoptions(Fitmodel);
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                
                for idxE = 1:length(Experiment)
                    t_full = Experiment(idxE).metadata.time;
                    t_full = [0; cumsum(diff(t_full))];
                    t_full.Format = 'mm:ss';
                    t_full = minutes(t_full);
                    
                    A1 = AnaphaseFit(t_full,dt,Experiment(idxE).A1,'A1');
                    if isempty(A1.Fit)
                        uialert(app.AnaSynchUIFigure,...
                            [app.data.Condition.Name;  {Experiment(idxE).metadata.FileName}],'Invalid anaphase A1 time window')
                        close(wb)
                        return
                    end
                    app.data.Condition(idxC).Experiment(idxE).A1 = A1;
                    
                    A2 = AnaphaseFit(t_full,dt,Experiment(idxE).A2,'A2');
                    if isempty(A2.Fit)
                        uialert(app.AnaSynchUIFigure,...
                            [app.data.Condition.Name;  {Experiment(idxE).metadata.FileName}],'Invalid anaphase A2 time window')
                        close(wb)
                        return
                    end
                    app.data.Condition(idxC).Experiment(idxE).A2 = A2;
                    
                    B = AnaphaseFit(t_full,dt,Experiment(idxE).B,'B');
                    if isempty(B.Fit)
                        uialert(app.AnaSynchUIFigure,...
                            [app.data.Condition.Name;  {Experiment(idxE).metadata.FileName}],'Invalid anaphase B time window')
                        close(wb)
                        return
                    end
                    app.data.Condition(idxC).Experiment(idxE).B = B;
                    
                    %****************************************************
                    %                     AnaphaseOnsetIdx = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    %                     t_full_A1 = t_full-t_full(AnaphaseOnsetIdx);
                    %                     tidx =  Experiment(idxE).A1.Distances.TimeIdx;
                    %                     t = t_full_A1(tidx);
                    %                     d = Experiment(idxE).A1.Distances.Mean;
                    %                     %                     idxKeep = (t0<=t) & (t<=t0+dt);
                    %                     idxKeep = (0<=t) & (t<=dt);
                    %                     t = t(idxKeep);
                    %                     d = d(idxKeep);
                    %                     if length(t)<=2
                    %                         uialert(app.AnaSynchUIFigure,...
                    %                             [app.data.Condition.Name;  {Experiment(idxE).metadata.FileName}],'Invalid anaphase A1 time window')
                    %                         close(wb)
                    %                         app.data.Condition(idxC).Experiment(idxE).A1.Fit = [];
                    %                         app.data.Condition(idxC).Experiment(idxE).A1.GoF = [];
                    %                         return
                    %                     end
                    %                     [t, d] = prepareCurveData(t,d);
                    %                     options.StartPoint = [-0.5 d(1)];
                    %                     [Fit_A1, gof_A1] = fit( t, d, Fitmodel, options);
                    %                     app.data.Condition(idxC).Experiment(idxE).A1.Fit = Fit_A1;
                    %                     app.data.Condition(idxC).Experiment(idxE).A1.GoF = gof_A1;
                    
                    %                     %****************************************************
                    %                     AnaphaseOnsetIdx = Experiment(idxE).A2.AnaphaseOnsetIdx;
                    %                     t_full_A2 = t_full-t_full(AnaphaseOnsetIdx);
                    %
                    %                     tidx =  Experiment(idxE).A2.Distances.TimeIdx;
                    %                     t = t_full_A2(tidx);
                    %                     d = Experiment(idxE).A2.Distances.Mean;
                    %                     %                     idxKeep = (t0<=t) & (t<=t0+dt);
                    %                     idxKeep = (0<=t) & (t<=dt);
                    %                     t = t(idxKeep);
                    %                     d = d(idxKeep);
                    %                     if length(t)<=2
                    %                         uialert(app.AnaSynchUIFigure,...
                    %                             [app.data.Condition.Name;  {Experiment(idxE).metadata.FileName}],'Invalid anaphase A2 time window')
                    %                         close(wb)
                    %                         app.data.Condition(idxC).Experiment(idxE).A2.Fit = [];
                    %                         app.data.Condition(idxC).Experiment(idxE).A2.GoF = [];
                    %                         return
                    %                     end
                    %                     [t, d] = prepareCurveData(t,d);
                    %                     options.StartPoint = [-0.5 d(1)];
                    %                     [Fit_A2, gof_A2] = fit( t, d, Fitmodel, options );
                    %                     app.data.Condition(idxC).Experiment(idxE).A2.Fit = Fit_A2;
                    %                     app.data.Condition(idxC).Experiment(idxE).A2.GoF = gof_A2;
                    %
                    %                     %****************************************************
                    %                     AnaphaseOnsetIdx = Experiment(idxE).B.AnaphaseOnsetIdx;
                    %                     t_full_B = t_full-t_full(AnaphaseOnsetIdx);
                    %
                    %                     tidx =  Experiment(idxE).B.Distances.TimeIdx;
                    %                     t = t_full_B(tidx);
                    %                     d = Experiment(idxE).B.Distances.Mean;
                    %                     %                     idxKeep = (t0<=t) & (t<=t0+dt);
                    %                     idxKeep = (0<=t) & (t<=dt);
                    %                     t = t(idxKeep);
                    %                     d = d(idxKeep);
                    %                     if length(t)<=2
                    %                         uialert(app.AnaSynchUIFigure,...
                    %                             [app.data.Condition.Name;  {Experiment(idxE).metadata.FileName}],'Invalid anaphase B time window')
                    %                         close(wb)
                    %                         app.data.Condition(idxC).Experiment(idxE).B.Fit  = [];
                    %                         app.data.Condition(idxC).Experiment(idxE).B.GoF  = [];
                    %                         return
                    %                     end
                    %                     [t, d] = prepareCurveData(t,d);
                    %                     options.StartPoint = [1 d(1)];
                    %                     [Fit_B, gof_B] = fit( t, d, Fitmodel, options );
                    %                     app.data.Condition(idxC).Experiment(idxE).B.Fit = Fit_B;
                    %                     app.data.Condition(idxC).Experiment(idxE).B.GoF = gof_B;
                end
            end
            close(wb)
            BuildTable(app)
            AnaphasePlot(app)
            SeparationSpeedPlot(app)
            KinetochoreSpread(app)
            KinetochoreSpreadPlot(app)
            MetaphaseWidth(app)
            MetaphaseWidthPlot(app)
        end

        % Menu selected function: KymographMenu
        function Kymograph(app, event)
            
            
            switch app.GroupSwitch.Value
                case 'Off'
                    
                    
                    fig = uifigure('Name',[app.data.Condition(app.Condition_idx).Name{:} ' ' app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).metadata.FileName]);
                    %                     fig.Units = 'normalized';
                    %                     'Units','pixels','Position',[10 10 750 900],...
%                     t0 = app.TimeafteronsetsSpinner.Value/60;
                    dt = app.FittingwindowSpinner.Value/60;
                    
                    t = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).metadata.time;
                    t = [0; cumsum(diff(t))];
                    t.Format = 'mm:ss';
                    t = minutes(t);
                    
                    OnsetIdx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.AnaphaseOnsetIdx;
                    t_A1 = t-t(OnsetIdx);
                    OnsetIdx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.AnaphaseOnsetIdx;
                    t_A2 = t-t(OnsetIdx);
                    OnsetIdx = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B.AnaphaseOnsetIdx;
                    t_B = t-t(OnsetIdx);
                    
                    tl = tiledlayout(fig,2,1,'TileSpacing','compact','Padding','compact');
                    ax1 = nexttile(tl,1);
                    ax2 = nexttile(tl,2);
                    im1 = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Prob.dist_time_prob;
                    im2 = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Prob.dist_time_prob;
                    
                    Dyn = [min([im1(:);im2(:)]) max([im1(:);im2(:)])];
                    
                    him1 = imshow(im1,Dyn,'Parent',ax1);
                    him2 = imshow(im2,Dyn,'Parent',ax2);
                    hc1 = colorbar(ax1);
                    hc2 = colorbar(ax2);
                    
                    if ~isempty(app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Fit)
                        tidx   = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Distances.TimeIdx;
                        tfit = t_A1(tidx);
%                         idxKeep = (t0<=tfit) & (tfit<=t0+dt);
                        idxKeep = (0<=tfit) & (tfit<=dt);
                        tfit = tfit(idxKeep);
                        Fitmodel = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Fit;
                        un = confint(Fitmodel);
                        un = un(:,1);
                        un = mean(abs(un-Fitmodel.a));
                        dfit = Fitmodel(tfit);
                        hold(ax1,'on')
                        hl1 = line(ax1,tfit,dfit,...
                            'Color',[1 1 1],...
                            'LineWidth',2);
                        hold(ax1,'off');
                        hleg1 = legend(ax1,hl1,['v = ' num2str(Fitmodel.a,'%0.2f') ' \pm ' num2str(un,'%0.2f') '\mum\cdotmin^{-1}'],...
                            'Color','none','EdgeColor','none','TextColor',[1 1 1],'Location','south');
                    end
                    
                    if ~isempty(app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Fit)
                        tidx   = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Distances.TimeIdx;
                        tfit = t_A2(tidx);
%                         idxKeep = (t0<=tfit) & (tfit<=t0+dt);
                        idxKeep = (0<=tfit) & (tfit<=dt);
                        tfit = tfit(idxKeep);
                        Fitmodel = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Fit;
                        un = confint(Fitmodel);
                        un = un(:,1);
                        un = mean(abs(un-Fitmodel.a));
                        dfit = Fitmodel(tfit);
                        hold(ax2,'on')
                        hl2 = line(ax2,tfit,dfit,...
                            'Color',[1 1 1],...
                            'LineWidth',2);
                        hold(ax2,'off');
                        legend(ax2,hl2,['v = ' num2str(Fitmodel.a,'%0.2f') ' \pm ' num2str(un,'%0.2f') '\mum\cdotmin^{-1}'],...
                            'Color','none','EdgeColor','none','TextColor',[1 1 1],'Location','south')
                    end
                    
                    
                    him1.XData = t_A1;
                    him1.YData = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1.Prob.distbin;
                    him1.YData = him1.YData;
                    ax1.Color  = [0 0 0];
                    ax1.Colormap = hot;
                    ax1.Visible  = 'on';
                    ax1.CLim = Dyn;
                    ax1.XLim = him1.XData([1 end])'+[-1 1]*mean(diff(him1.XData))/2;
                    ax1.YLim = him1.YData([1 end]) +[-1 1]*mean(diff(him1.YData))/2;
                    ax1.YDir = 'normal';
                    ax1.XMinorTick = 'on';
                    ax1.YMinorTick = 'on';
                    ax1.XGrid = 'on';
                    ax1.YGrid = 'on';
                    ax1.XMinorGrid = 'on';
                    ax1.YMinorGrid = 'on';
                    ax1.GridColor = [1 1 1];
                    ax1.MinorGridColor = [1 1 1];
                    ax1.XLabel.String = 'time (min)';
                    ax1.YLabel.String = 'Kinetochore-to-Pole distance (\mum)';
                    ax1.Title.String  = 'Pole 1';
                    
                    
                    him2.XData = t_A2;
                    him2.YData = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2.Prob.distbin;
                    him2.YData = him2.YData;
                    ax2.Color  = [0 0 0];
                    ax2.Colormap = hot;
                    ax2.Visible  = 'on';
                    ax2.CLim = Dyn;
                    ax2.XLim = him2.XData([1 end])'+[-1 1]*mean(diff(him2.XData))/2;
                    ax2.YLim = him2.YData([1 end]) +[-1 1]*mean(diff(him2.YData))/2;
                    ax2.YDir = 'normal';
                    ax2.XMinorTick = 'on';
                    ax2.YMinorTick = 'on';
                    ax2.XGrid = 'on';
                    ax2.YGrid = 'on';
                    ax2.XMinorGrid = 'on';
                    ax2.YMinorGrid = 'on';
                    ax2.GridColor = [1 1 1];
                    ax2.MinorGridColor = [1 1 1];
                    ax2.XLabel.String = 'time (min)';
                    ax2.YLabel.String = 'Kinetochore-to-Pole distance (\mum)';
                    ax2.Title.String  = 'Pole 2';
                    
                    hc1.Label.String = 'Kinetochores position probability (-)';
                    hc2.Label.String = 'Kinetochores position probability (-)';
                    
                    linkaxes([ax1 ax2])
                    movegui(fig,'center')
                    
                    
                case 'On'
                    fig = uifigure('Name',[app.data.Condition(app.Condition_idx).Name{:}]);
                    %                     t0 = app.TimeafteronsetsSpinner.Value/60;
                    %                     dt = app.FittingwindowSpinner.Value/60;
                    
                    
                    tl = tiledlayout(fig,3,1,'TileSpacing','compact','Padding','compact');
                    ax1 = nexttile(tl,1);
                    ax2 = nexttile(tl,2);
                    ax3 = nexttile(tl,3);
                    
                    Experiment = app.data.Condition(app.Condition_idx).Experiment;
                    d_A1 = cell(length(Experiment),1);
                    d_A2 = cell(length(Experiment),1);
                    d_B  = cell(length(Experiment),1);
                    t_A1 = cell(length(Experiment),1);
                    t_A2 = cell(length(Experiment),1);
                    t_B  = cell(length(Experiment),1);
                    
                    for idxE = 1:length(Experiment)
                        t = Experiment(idxE).metadata.time;
                        t = [0; cumsum(diff(t))];
                        t.Format = 'mm:ss';
                        t = minutes(t);
                        OnsetIdx = Experiment(idxE).A1.AnaphaseOnsetIdx;
                        t_A1_tmp = t-t(OnsetIdx);
                        OnsetIdx = Experiment(idxE).A2.AnaphaseOnsetIdx;
                        t_A2_tmp = t-t(OnsetIdx);
                        OnsetIdx = Experiment(idxE).B.AnaphaseOnsetIdx;
                        t_B_tmp  = t-t(OnsetIdx);
                        
                        tidx   = Experiment(idxE).A1.Distances.TimeIdx;
                        d_mean = Experiment(idxE).A1.Distances.Mean;
                        M = mean(d_mean(1:OnsetIdx));
                        d_A1{idxE} = d_mean/M;
                        t_A1{idxE} = t_A1_tmp(tidx);
                        
                        tidx   = Experiment(idxE).A2.Distances.TimeIdx;
                        d_mean = Experiment(idxE).A2.Distances.Mean;
                        M = mean(d_mean(1:OnsetIdx));
                        d_A2{idxE} = d_mean/M;
                        t_A2{idxE} = t_A2_tmp(tidx);
                        
                        tidx   = Experiment(idxE).B.Distances.TimeIdx;
                        d_mean = Experiment(idxE).B.Distances.Mean;
                        M = mean(d_mean(1:OnsetIdx));
                        d_B{idxE} = d_mean/M;
                        t_B{idxE} = t_B_tmp(tidx);
                    end
                    t_all  = unique([cat(1,t_A1{:}) cat(1,t_A2{:}) cat(1,t_B{:})]);
                    t_InterpS = max(diff(t_all));
                    t_Interp  = unique([ [min(t_all):t_InterpS:0] [0:t_InterpS:max(t_all)]]);
                    
                    d_A1_interp = nan(length(Experiment),length(t_Interp));
                    d_A2_interp = nan(length(Experiment),length(t_Interp));
                    d_B_interp  = nan(length(Experiment),length(t_Interp));
                    for idxE = 1:length(Experiment)
                        t = t_A1{idxE};
                        idx_interp = t(1)<=t_Interp & t_Interp<=t(end);
                        d_A1_interp(idxE,idx_interp) = interp1(t,d_A1{idxE},t_Interp(idx_interp),'linear');
                        
                        t = t_A2{idxE};
                        idx_interp = t(1)<=t_Interp & t_Interp<=t(end);
                        d_A2_interp(idxE,idx_interp) = interp1(t,d_A2{idxE},t_Interp(idx_interp),'linear');
                        
                        t = t_B{idxE};
                        idx_interp = t(1)<=t_Interp & t_Interp<=t(end);
                        d_B_interp(idxE,idx_interp) = interp1(t,d_B{idxE},t_Interp(idx_interp),'linear');
                    end
                    
                    SpatialBin = 0.05;
                    edgesA = [min([d_A1_interp(:),d_A2_interp(:)]):SpatialBin:max([d_A1_interp(:),d_A2_interp(:)])];
                    edgesB = [min(d_B_interp(:)):SpatialBin:max(d_B_interp(:))];
                    
                    N_A1 = zeros(length(edgesA)-1,length(t_Interp));
                    N_A2 = zeros(length(edgesA)-1,length(t_Interp));
                    N_B  = zeros(length(edgesB)-1,length(t_Interp));
                    for idxT = 1:length(t_Interp)
                        N_A1(:,idxT) = histcounts(d_A1_interp(:,idxT),edgesA,'Normalization','probability');
                        N_A2(:,idxT) = histcounts(d_A2_interp(:,idxT),edgesA,'Normalization','probability');
                        N_B(:,idxT)  = histcounts(d_B_interp(:,idxT), edgesB,'Normalization','probability');
                    end
                    edgesA = edgesA(1:end-1)+SpatialBin/2;
                    edgesB = edgesB(1:end-1)+SpatialBin/2;
                    
                    DynA = [min([N_A1(:);N_A2(:)]) max([N_A1(:);N_A2(:)])];
                    DynB = [min(N_B(:)) max(N_B(:))];
                    
                    
                    him1 = imshow(N_A1,DynA,'Parent',ax1);
                    him1.XData = t_Interp;
                    him1.YData = edgesA;
                    ax1.Colormap = hot;
                    ax1.Visible  = 'on';
                    ax1.CLim = DynA;
                    ax1.XLim = him1.XData([1 end])+[-1 1]*mean(diff(him1.XData))/2;
                    ax1.YLim = him1.YData([1 end]) +[-1 1]*mean(diff(him1.YData))/2;
                    ax1.YDir = 'normal';
                    ax1.XMinorTick = 'on';
                    ax1.YMinorTick = 'on';
                    ax1.XGrid = 'on';
                    ax1.YGrid = 'on';
                    ax1.XMinorGrid = 'on';
                    ax1.YMinorGrid = 'on';
                    ax1.Color = [0 0 0];
                    ax1.GridColor = [1 1 1];
                    ax1.MinorGridColor = [1 1 1];
                    ax1.DataAspectRatioMode = 'Auto';
                    ax1.YLabel.String = {'Kinetochore-to-Pole distance (-)'};
                    ax1.Title.String  = {'Anaphase A - Pole 1','all experiments average, before anaphase onset normalization'};
                    
                    
                    him2 = imshow(N_A2,DynA,'Parent',ax2);
                    him2.XData = t_Interp;
                    him2.YData = edgesA;
                    ax2.Colormap = hot;
                    ax2.Visible  = 'on';
                    ax2.CLim = DynA;
                    ax2.XLim = him2.XData([1 end])+[-1 1]*mean(diff(him2.XData))/2;
                    ax2.YLim = him2.YData([1 end]) +[-1 1]*mean(diff(him2.YData))/2;
                    ax2.YDir = 'normal';
                    ax2.XMinorTick = 'on';
                    ax2.YMinorTick = 'on';
                    ax2.XGrid = 'on';
                    ax2.YGrid = 'on';
                    ax2.XMinorGrid = 'on';
                    ax2.YMinorGrid = 'on';
                    ax2.GridColor = [1 1 1];
                    ax2.MinorGridColor = [1 1 1];
                    ax2.Color = [0 0 0];
                    ax2.DataAspectRatioMode = 'Auto';
                    ax2.YLabel.String = {'Kinetochore-to-Pole distance (-)'};
                    ax2.Title.String = {'Anaphase A - Pole 2','all experiments average, before anaphase onset normalization'};
                    
                    him3 = imshow(N_B,DynB,'Parent',ax3);
                    him3.XData = t_Interp;
                    him3.YData = edgesB;
                    ax3.Colormap = hot;
                    ax3.Visible  = 'on';
                    ax3.CLim = DynB;
                    ax3.XLim = him3.XData([1 end])+[-1 1]*mean(diff(him3.XData))/2;
                    ax3.YLim = him3.YData([1 end]) +[-1 1]*mean(diff(him3.YData))/2;
                    ax3.YDir = 'normal';
                    ax3.XMinorTick = 'on';
                    ax3.YMinorTick = 'on';
                    ax3.XGrid = 'on';
                    ax3.YGrid = 'on';
                    ax3.XMinorGrid = 'on';
                    ax3.YMinorGrid = 'on';
                    ax3.GridColor = [1 1 1];
                    ax3.MinorGridColor = [1 1 1];
                    ax3.DataAspectRatioMode = 'Auto';
                    ax3.YLabel.String = {'Pole-to-Pole distance (-)'};
                    ax3.XLabel.String = 'time (min)';
                    ax3.Title.String = {'Anaphase B','all experiments average, before anaphase onset normalization'};
                    
                    hc1 = colorbar(ax1);
                    hc1.Label.String = 'Kinetochores position probability (-)';
                    
                    hc2 = colorbar(ax2);
                    hc2.Label.String = 'Kinetochores position probability (-)';
                    
                    hc3 = colorbar(ax3);
                    hc3.Label.String = 'Centrosomes distance probability (-)';
                    
                    
                    linkaxes([ax1 ax2 ax3],'x')
                    
                    
            end
            
        end

        % Value changed function: DropDown, Spinner, 
        % TypeofchangeDropDown
        function AnaphaseOnsetAuto(app, event)
            
            switch app.DropDown.Value
                case 'Threshold'
                    app.Spinner.RoundFractionalValues = false;
                    app.Spinner.Step = 0.01;
                    app.Spinner.Limits = [0 Inf];
                case 'MaxNumChanges'
                    app.Spinner.Step = 1;
                    app.Spinner.Limits = [1 Inf];
                    app.Spinner.RoundFractionalValues = true;
            end
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;
                for idxE = 1:length(Experiment)
                    AnaphaseOnsetIdx = find(ischange(Experiment(idxE).B.Distances.Mean,...
                        app.TypeofchangeDropDown.Value,...
                        app.DropDown.Value,...
                        app.Spinner.Value),...
                        1);
                    app.data.Condition(idxC).Experiment(idxE).A1.AnaphaseOnsetIdx = AnaphaseOnsetIdx;
                    app.data.Condition(idxC).Experiment(idxE).A2.AnaphaseOnsetIdx = AnaphaseOnsetIdx;
                    app.data.Condition(idxC).Experiment(idxE).B.AnaphaseOnsetIdx = AnaphaseOnsetIdx;
                end
            end
            AnaphaseFittingAll(app)
            AnaphasePlot(app)
            
        end

        % Value changed function: A1Spinner, A2Spinner, BSpinner
        function AnaphaseOnsetManual(app, event)
            value = event.Value;
            
            
            dt = app.FittingwindowSpinner.Value/60;
            experiment = app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx);
            
            t = experiment.metadata.time;
            t = [0; cumsum(diff(t))];
            t.Format = 'mm:ss';
            t = minutes(t);
            
            switch event.Source.Tag
                case 'A1ManOnset'
                    app.A1Spinner.Value = 0;
                    anaphase = experiment.A1;
                    if 0<anaphase.AnaphaseOnsetIdx+value && anaphase.AnaphaseOnsetIdx+value<experiment.metadata.DimT-2
                        anaphase.AnaphaseOnsetIdx = anaphase.AnaphaseOnsetIdx+value;
                    else
                        return
                    end
                    anaphase = AnaphaseFit(t,dt,anaphase,'A1');
                    if isempty(anaphase.Fit)
                        uialert(app.AnaSynchUIFigure,...
                            [app.data.Condition(app.Condition_idx).Name;  {experiment.metadata.FileName}],'Invalid anaphase A1 time window')
                        return
                    end
                    app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A1 = anaphase;
                    
                case 'A2ManOnset'
                    app.A2Spinner.Value = 0;
                    anaphase = experiment.A2;
                    if 0<anaphase.AnaphaseOnsetIdx+value && anaphase.AnaphaseOnsetIdx+value<experiment.metadata.DimT-2
                        anaphase.AnaphaseOnsetIdx = anaphase.AnaphaseOnsetIdx+value;
                    else
                        return
                    end
                    anaphase = AnaphaseFit(t,dt,anaphase,'A2');
                    if isempty(anaphase.Fit)
                        uialert(app.AnaSynchUIFigure,...
                            [app.data.Condition(app.Condition_idx).Name;  {experiment.metadata.FileName}],'Invalid anaphase A2 time window')
                        return
                    end
                    app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).A2 = anaphase;
                    
                case 'BManOnset'
                    app.BSpinner.Value = 0;
                    anaphase = experiment.B;
                    if 0<anaphase.AnaphaseOnsetIdx+value && anaphase.AnaphaseOnsetIdx+value<experiment.metadata.DimT-2
                        anaphase.AnaphaseOnsetIdx = anaphase.AnaphaseOnsetIdx+value;
                    else
                        return
                    end
                    anaphase = AnaphaseFit(t,dt,anaphase,'B');
                    if isempty(anaphase.Fit)
                        uialert(app.AnaSynchUIFigure,...
                            [app.data.Condition(app.Condition_idx).Name;  {experiment.metadata.FileName}],'Invalid anaphase B time window')
                        return
                    end
                    app.data.Condition(app.Condition_idx).Experiment(app.Experiment_idx).B = anaphase;
            end
            BuildTable(app)
            AnaphasePlot(app)
            SeparationSpeedPlot(app)
            KinetochoreSpread(app)
            KinetochoreSpreadPlot(app)
            MetaphaseWidth(app)
            MetaphaseWidthPlot(app)
            
            
        end

        % Menu selected function: AnaphasesspeedMenu
        function StatSpeed(app, event)
            alpha = 0.05;
            
            Conditions = categorical([app.data.Condition.Name]);
            v_A1 = cell(length(app.data.Condition),1);
            v_A2 = cell(length(app.data.Condition),1);
            v_B  = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(Conditions)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                v_A1{idxC} = cell2mat(arrayfun(@(x) x.A1.Fit.a,Experiment,'UniformOutput',false));
                v_A2{idxC} = cell2mat(arrayfun(@(x) x.A2.Fit.a,Experiment,'UniformOutput',false));
                v_B{idxC}  = cell2mat(arrayfun(@(x) x.B.Fit.a,Experiment, 'UniformOutput',false));
            end
            g_names  = repelem([app.data.Condition.Name],cellfun(@length,v_A1))';
            ExpNames = cat(1,ExpNames{:});
            allv_A1  = cat(1,v_A1{:});
            allv_A2  = cat(1,v_A2{:});
            allv_B   = cat(1,v_B{:});
            
            Meas = table(g_names,ExpNames,allv_A1,allv_A2,allv_B);
            Meas.Properties.VariableNames = {'Condition','Experience','v_A1','v_A2','v_B'};
            Meas.Experience = categorical(Meas.Experience);
            
            %             isPoolPole = Pole1vsPole2Speed(app,Meas,alpha);
            figtitle = 'Anaphase A speed pole 1 vs pole 2';
            Measlbl  = 'Separation speed (\mum\cdotmin^{-1})';
            questlbl = 'speed';
            isPoolPole = Pole1vsPole2(app,Meas(:,{'Condition','Experience','v_A1','v_A2'}),alpha,figtitle,Measlbl,questlbl);
            
            if isPoolPole
                v_A1 = Meas(:,{'Condition','Experience','v_A1'});
                v_A2 = Meas(:,{'Condition','Experience','v_A2'});
                v_A1.Properties.VariableNames(end) = {'speed'};
                v_A2.Properties.VariableNames(end) = {'speed'};
                v_A = [v_A1;v_A2];
                Test_A1 = StatTest(app,v_A(:,{'Condition','speed'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Anaphase A speed poles together'))
                end
                fig_A = uifigure('Name','Anaphase A speed  poles together');
                gl = uigridlayout(fig_A,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = v_A.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),v_A.speed(idxKeepM),v_A.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                
                boxplot(ax(length(Conditions)+1),v_A.speed,v_A.Condition)
                ax(length(Conditions)+1).YDir = 'reverse';
                ax(length(Conditions)+1).YLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Anaphase A speed poles together';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A1;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m&middot;min<sup>-1</sup>';
                h.Data = htmlData;


            else
                Test_A1 = StatTest(app,Meas(:,{'Condition','v_A1'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Anaphase A speed at pole 1'))
                end
                fig_A1 = uifigure('Name','Anaphase A speed at pole 1');
                gl = uigridlayout(fig_A1,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = Meas.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),Meas.v_A1(idxKeepM),Meas.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                
                boxplot(ax(length(Conditions)+1),Meas.v_A1,Meas.Condition)
                ax(length(Conditions)+1).YDir = 'reverse';
                ax(length(Conditions)+1).YLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Anaphase A speed at pole 1';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A1;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m&middot;min<sup>-1</sup>';
                h.Data = htmlData;
                
                
                Test_A2 = StatTest(app,Meas(:,{'Condition','v_A2'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Anaphase A speed at pole 2'))
                end
                fig_A2 = uifigure('Name','Anaphase A speed at pole 2');
                gl = uigridlayout(fig_A2,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = Meas.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),Meas.v_A2(idxKeepM),Meas.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                boxplot(ax(length(Conditions)+1),Meas.v_A2,Meas.Condition)
                ax(length(Conditions)+1).YDir = 'reverse';
                ax(length(Conditions)+1).YLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Anaphase A speed at pole 2';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A2;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m&middot;min<sup>-1</sup>';
                h.Data = htmlData;
            end


            Test_B = StatTest(app,Meas(:,{'Condition','v_B'}),Conditions,alpha);
            try
                close(findall(groot,'type','figure','Name','Anaphase B speed'))
            end
            fig_B = uifigure('Name','Anaphase B speed');
            gl = uigridlayout(fig_B,[2,length(Conditions)]);
            ax = gobjects(length(Conditions)+2);
            cmap = lines(length(app.data.Condition));
            for idxC = 1:length(Conditions)
                ax(idxC) = uiaxes(gl);
                ax(idxC).Layout.Row = 1;
                ax(idxC).Layout.Column = idxC;
                idxKeepM = Meas.Condition == Conditions(idxC);
                %                 idxKeepN = Normality.Condition == Conditions(idxC);
                htmp = mynormplot( ax(idxC),Meas.v_B(idxKeepM),Meas.Experience(idxKeepM));
                htmp(1).Marker = 'o';
                htmp(1).MarkerFaceColor = cmap(idxC,:);
                htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                htmp(1).MarkerSize = 4;
                htmp(2).Color = cmap(idxC,:);
                htmp(3).Color = cmap(idxC,:);
                ax(idxC).XLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
                ax(idxC).Title.String  = char(Conditions(idxC));
                ax(idxC).Box = 'on';
            end
            ax(length(Conditions)+1) = uiaxes(gl);
            ax(length(Conditions)+1).Layout.Row = 2;
            if  length(Conditions) == 2
                ax(length(Conditions)+1).Layout.Column = 1;
            else
                ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
            end
            boxplot(ax(length(Conditions)+1),Meas.v_B,Meas.Condition)
            ax(length(Conditions)+1).YDir = 'normal';
            ax(length(Conditions)+1).YLabel.String = 'Separation speed (\mum\cdotmin^{-1})';
            ax(length(Conditions)+1).XLabel.String = 'Conditions';
            
            ax(length(Conditions)+1).Title.String = 'Anaphase B';
            h = uihtml(gl);
            h.HTMLSource = 'stat_pannel.html';
            htmlData = [];
            htmlData = Test_B;
            htmlData.alpha = alpha;
            htmlData.Conditions = Conditions;
            htmlData.Unit = '&mu;m&middot;min<sup>-1</sup>';
            h.Data = htmlData;
            
            %


        end

        % Menu selected function: NonnormalizedspreadMenu
        function StatSpread(app, event)
            alpha = 0.05;
            Conditions = categorical([app.data.Condition.Name]);
            s_A1 = cell(length(app.data.Condition),1);
            s_A2 = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(Conditions)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                s_A1{idxC} = cell2mat(arrayfun(@(x) x.A1.Kshift,Experiment,'UniformOutput',false));
                s_A2{idxC} = cell2mat(arrayfun(@(x) x.A2.Kshift,Experiment,'UniformOutput',false));
            end
            g_names  = repelem([app.data.Condition.Name],cellfun(@length,s_A1))';
            ExpNames = cat(1,ExpNames{:});
            alls_A1  = cat(1,s_A1{:});
            alls_A2  = cat(1,s_A2{:});
            
            
            Meas = table(g_names,ExpNames,alls_A1,alls_A2);
            Meas.Properties.VariableNames = {'Condition','Experience','s_A1','s_A2'};
            Meas.Experience = categorical(Meas.Experience);
            
            figtitle = 'Anaphase A spread pole 1 vs pole 2';
            Measlbl  = 'Kinetochores spread (\mum)';
            questlbl = 'shift';
            isPoolPole = Pole1vsPole2(app,Meas,alpha,figtitle,Measlbl,questlbl);
            if isPoolPole
                s_A1 = Meas(:,{'Condition','Experience','s_A1'});
                s_A2 = Meas(:,{'Condition','Experience','s_A2'});
                s_A1.Properties.VariableNames(end) = {'Spread'};
                s_A2.Properties.VariableNames(end) = {'Spread'};
                s_A = [s_A1;s_A2];
                Test_A = StatTest(app,s_A(:,{'Condition','Spread'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Kinetochores spread poles together'))
                end
                fig_A = uifigure('Name','Kinetochores spread poles together');
                gl = uigridlayout(fig_A,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = s_A.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),s_A.Spread(idxKeepM),s_A.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Kinetochores spread (\mum)';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                
                boxplot(ax(length(Conditions)+1),s_A.Spread,s_A.Condition)
                ax(length(Conditions)+1).YDir = 'reverse';
                ax(length(Conditions)+1).YLabel.String = 'Kinetochores spread (\mum)';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Kinetochores spread poles together';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m';
                h.Data = htmlData;


            else
                
                Test_A1 = StatTest(app,Meas(:,{'Condition','s_A1'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Anaphase A spread at pole 1'))
                end
                fig_A1 = uifigure('Name','Anaphase A spread at pole 1');
                gl = uigridlayout(fig_A1,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = Meas.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),Meas.s_A1(idxKeepM),Meas.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Kinetochores spread (\mum)';
                    %                 if Normality.vA1_pval(idxKeepN) >0.001
                    %                     ax(idxC).Title.String  = {char(Conditions(idxC)), ['Lilliefors normality test: p-val = ' num2str(Normality.vA1_pval(idxKeepN),'%0.2f')]};
                    %                 else
                    %                     ax(idxC).Title.String  = {char(Conditions(idxC)), ['Lilliefors normality test: p-val = ' replace(num2str(Normality.vA1_pval(idxKeepN),'%0.2e'),'e','\cdot10^{') '}']};
                    %                 end
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                
                boxplot(ax(length(Conditions)+1),Meas.s_A1,Meas.Condition)
                ax(length(Conditions)+1).YLabel.String = 'Kinetochores spread (\mum)';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Kinetochores spread at pole 1';
                h = uihtml(gl);
                %             h.HTMLSource = 'anova.html';
                %             [~,idx ] = ismember(Meas.Condition,ax(length(Conditions)+1).XTickLabel);
                %             n = splitapply(@numel,Meas.v_A1,idx);
                %             m = splitapply(@mean,Meas.v_A1,idx);
                %             s = splitapply(@std,Meas.v_A1,idx);
                h.HTMLSource = 'stat_pannel.html';
                %             switch Test_A1.TestName{:}
                %                 case 't-test equal variance'
                %                     h.HTMLSource = 't-test.html';
                %                 case 't-test unequal variance'
                %                     h.HTMLSource = 't-test.html';
                %                 case 'Mann-Whitney U-test'
                %                     h.HTMLSource = 'Mann-Whitney.html';
                %                 case 'One-way analysis of variance'
                %                     h.HTMLSource = 'anova.html';
                %                 case 'Kruskal-Wallis test'
                %                     h.HTMLSource = 'Kruskal-Wallis.html';
                htmlData = [];
                htmlData = Test_A1;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m';%'"&mu;m&middot;min<sup>-1</sup>'
                h.Data = htmlData;
                
                
                Test_A2 = StatTest(app,Meas(:,{'Condition','s_A2'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Anaphase A spread at pole 2'))
                end
                fig_A2 = uifigure('Name','Anaphase A spread at pole 2');
                gl = uigridlayout(fig_A2,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = Meas.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),Meas.s_A2(idxKeepM),Meas.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Kinetochores spread (\mum)';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                boxplot(ax(length(Conditions)+1),Meas.s_A2,Meas.Condition)
                ax(length(Conditions)+1).YLabel.String = 'Kinetochore spread (\mum)';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Kinetochores spread at pole 2';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A2;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m';%'"&mu;m&middot;min<sup>-1</sup>'
                h.Data = htmlData;
            end
            
            
            %
        end

        % Menu selected function: AnaphasesspeeddispersionMenu
        function StatSpeedDispersion(app, event)
            alpha = 0.05;
            
            Conditions = categorical([app.data.Condition.Name]);
            v_A1 = cell(length(app.data.Condition),1);
            v_A2 = cell(length(app.data.Condition),1);
            v_B  = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(Conditions)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                v_A1{idxC} = cell2mat(arrayfun(@(x) x.A1.Fit.a,Experiment,'UniformOutput',false));
                v_A2{idxC} = cell2mat(arrayfun(@(x) x.A2.Fit.a,Experiment,'UniformOutput',false));
                v_B{idxC}  = cell2mat(arrayfun(@(x) x.B.Fit.a,Experiment, 'UniformOutput',false));
            end
            g_names  = repelem([app.data.Condition.Name],cellfun(@length,v_A1))';
            ExpNames = cat(1,ExpNames{:});
            allv_A1  = cat(1,v_A1{:});
            allv_A2  = cat(1,v_A2{:});
            allv_B   = cat(1,v_B{:});
            
            Meas = table(g_names,ExpNames,allv_A1,allv_A2,allv_B);
            Meas.Properties.VariableNames = {'Condition','Experience','v_A1','v_A2','v_B'};
            Meas.Experience = categorical(Meas.Experience);
            
            figtitle = 'Anaphase A speed dispersion pole 1 vs pole 2';
            Measlbl  = 'Separation speed dispersion (\mum\cdotmin^{-1})';
            questlbl = 'speed';
            isPoolPole = Pole1vsPole2(app,Meas(:,{'Condition','Experience','v_A1','v_A2'}),alpha,figtitle,Measlbl,questlbl);
            if isPoolPole
                v_A1 = Meas(:,{'Condition','Experience','v_A1'});
                v_A2 = Meas(:,{'Condition','Experience','v_A2'});
                v_A1.Properties.VariableNames(end) = {'speed'};
                v_A2.Properties.VariableNames(end) = {'speed'};
                v_A = [v_A1;v_A2];%
                [p,stats] = myvartestn(v_A.speed,v_A.Condition,alpha,'Anaphase A Poles together','TestType','LeveneAbsolute');
                
            else
                [p,stats] = myvartestn(Meas.v_A1,Meas.Condition,alpha,'Pole A1','TestType','LeveneAbsolute');
                [p,stats] = myvartestn(Meas.v_A2,Meas.Condition,alpha,'Pole A2','TestType','LeveneAbsolute');
                
            end
            [p,stats] = myvartestn(Meas.v_B,Meas.Condition,alpha,'Anaphase B','TestType','LeveneAbsolute');
            
            
        end

        % Menu selected function: MetaphaseplatewidthMenu
        function StatMetaWidth(app, event)
            alpha = 0.05;
            Conditions = categorical([app.data.Condition.Name]);
            mw = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(Conditions)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                mw{idxC}  = cell2mat(arrayfun(@(x) x.MetaphasePlate.MetaWidth,Experiment, 'UniformOutput',false));
            end
            g_names  = repelem([app.data.Condition.Name],cellfun(@length,mw))';
            ExpNames = cat(1,ExpNames{:});
            all_mw   = cat(1,mw{:});
            
            Meas = table(g_names,ExpNames,all_mw);
            Meas.Properties.VariableNames = {'Condition','Experience','MetaWidth'};
            Meas.Experience = categorical(Meas.Experience);
            
            Test_MW = StatTest(app,Meas(:,{'Condition','MetaWidth'}),Conditions,alpha);
            try
                close(findall(groot,'type','figure','Name','Metaphase plate width at anaphase onset'))
            end
            fig_MW = uifigure('Name','Metaphase plate width at anaphase onset');
            gl = uigridlayout(fig_MW,[2,length(Conditions)]);
            ax = gobjects(length(Conditions)+2);
            cmap = lines(length(app.data.Condition));
            for idxC = 1:length(Conditions)
                ax(idxC) = uiaxes(gl);
                ax(idxC).Layout.Row = 1;
                ax(idxC).Layout.Column = idxC;
                idxKeepM = Meas.Condition == Conditions(idxC);
                %                 idxKeepN = Normality.Condition == Conditions(idxC);
                htmp = mynormplot( ax(idxC),Meas.MetaWidth(idxKeepM),Meas.Experience(idxKeepM));
                htmp(1).Marker = 'o';
                htmp(1).MarkerFaceColor = cmap(idxC,:);
                htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                htmp(1).MarkerSize = 4;
                htmp(2).Color = cmap(idxC,:);
                htmp(3).Color = cmap(idxC,:);
                ax(idxC).XLabel.String = 'Kinetochores standard deviation (\mum)';
                ax(idxC).Title.String  = char(Conditions(idxC));
                ax(idxC).Box = 'on';
            end
            ax(length(Conditions)+1) = uiaxes(gl);
            ax(length(Conditions)+1).Layout.Row = 2;
            if  length(Conditions) == 2
                ax(length(Conditions)+1).Layout.Column = 1;
            else
                ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
            end
            
            boxplot(ax(length(Conditions)+1),Meas.MetaWidth,Meas.Condition)
            ax(length(Conditions)+1).YDir = 'normal';
            ax(length(Conditions)+1).YLabel.String = 'Kinetochores standard deviation (\mum)';
            ax(length(Conditions)+1).XLabel.String = 'Conditions';
            
            ax(length(Conditions)+1).Title.String = 'Metaphase plate width at anaphase onset';
            h = uihtml(gl);
            h.HTMLSource = 'stat_pannel.html';
            htmlData = [];
            htmlData = Test_MW;
            htmlData.alpha = alpha;
            htmlData.Conditions = Conditions;
            htmlData.Unit = '&mu;m';
            h.Data = htmlData;
            
        end

        % Menu selected function: 
        % MetaphaseplatewidthnormalizedspreadMenu
        function StatNormSpread(app, event)
            alpha = 0.05;
            Conditions = categorical([app.data.Condition.Name]);
            s_A1 = cell(length(app.data.Condition),1);
            s_A2 = cell(length(app.data.Condition),1);
            ExpNames = cell(length(app.data.Condition),1);
            
            for idxC = 1:length(Conditions)
                Experiment = app.data.Condition(idxC).Experiment;
                ExpNames{idxC} = arrayfun(@(x) x.metadata.FileName,Experiment,'UniformOutput',false);
                s_A1{idxC} = cell2mat(arrayfun(@(x) x.A1.Kshift,Experiment,'UniformOutput',false));
                s_A2{idxC} = cell2mat(arrayfun(@(x) x.A2.Kshift,Experiment,'UniformOutput',false));
                mw   = cell2mat(arrayfun(@(x) x.MetaphasePlate.MetaWidth,Experiment, 'UniformOutput',false));
                s_A1{idxC} = s_A1{idxC}./mw;
                s_A2{idxC} = s_A2{idxC}./mw;
                
            end
            g_names  = repelem([app.data.Condition.Name],cellfun(@length,s_A1))';
            ExpNames = cat(1,ExpNames{:});
            alls_A1  = cat(1,s_A1{:});
            alls_A2  = cat(1,s_A2{:});
            
            
            Meas = table(g_names,ExpNames,alls_A1,alls_A2);
            Meas.Properties.VariableNames = {'Condition','Experience','s_A1','s_A2'};
            Meas.Experience = categorical(Meas.Experience);
            
            figtitle = 'Anaphase A normalized spread pole 1 vs pole 2';
            Measlbl  = 'Kinetochores spread (-)';
            questlbl = 'shift';
            isPoolPole = Pole1vsPole2(app,Meas,alpha,figtitle,Measlbl,questlbl);
            
            if isPoolPole
                s_A1 = Meas(:,{'Condition','Experience','s_A1'});
                s_A2 = Meas(:,{'Condition','Experience','s_A2'});
                s_A1.Properties.VariableNames(end) = {'NormSpread'};
                s_A2.Properties.VariableNames(end) = {'NormSpread'};
                s_A = [s_A1;s_A2];
                Test_A = StatTest(app,s_A(:,{'Condition','NormSpread'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Kinetochores spread poles together normalized by metaphase plate width'))
                end
                fig_A = uifigure('Name','Kinetochores spread poles together normalized by metaphase plate width');
                gl = uigridlayout(fig_A,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = s_A.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),s_A.NormSpread(idxKeepM),s_A.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Kinetochores normalized spread (-)';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                
                boxplot(ax(length(Conditions)+1),s_A.NormSpread,s_A.Condition)
                ax(length(Conditions)+1).YDir = 'reverse';
                ax(length(Conditions)+1).YLabel.String = 'Kinetochores normalized spread (-)';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Kinetochores spread at poles together normalized by metaphase plate width';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '-';
                h.Data = htmlData;
                
                
            else
                
                Test_A1 = StatTest(app,Meas(:,{'Condition','s_A1'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Kinetochores spread at pole 1 normalized by metaphase plate width'))
                end
                fig_A1 = uifigure('Name','Kinetochores spread at pole 1 normalized by metaphase plate width');
                gl = uigridlayout(fig_A1,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = Meas.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),Meas.s_A1(idxKeepM),Meas.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Kinetochores normalized spread (-)';
                    %                 if Normality.vA1_pval(idxKeepN) >0.001
                    %                     ax(idxC).Title.String  = {char(Conditions(idxC)), ['Lilliefors normality test: p-val = ' num2str(Normality.vA1_pval(idxKeepN),'%0.2f')]};
                    %                 else
                    %                     ax(idxC).Title.String  = {char(Conditions(idxC)), ['Lilliefors normality test: p-val = ' replace(num2str(Normality.vA1_pval(idxKeepN),'%0.2e'),'e','\cdot10^{') '}']};
                    %                 end
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                
                boxplot(ax(length(Conditions)+1),Meas.s_A1,Meas.Condition)
                ax(length(Conditions)+1).YLabel.String = 'Kinetochores normalized spread (-)';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Kinetochores spread at pole 1 normalized by metaphase plate width';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A1;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '&mu;m';%'"&mu;m&middot;min<sup>-1</sup>'
                h.Data = htmlData;
                
                
                Test_A2 = StatTest(app,Meas(:,{'Condition','s_A2'}),Conditions,alpha);
                try
                    close(findall(groot,'type','figure','Name','Kinetochores spread at pole 2 normalized by metaphase plate width'))
                end
                fig_A2 = uifigure('Name','Kinetochores spread at pole 2 normalized by metaphase plate width');
                gl = uigridlayout(fig_A2,[2,length(Conditions)]);
                ax = gobjects(length(Conditions)+2);
                cmap = lines(length(app.data.Condition));
                for idxC = 1:length(Conditions)
                    ax(idxC) = uiaxes(gl);
                    ax(idxC).Layout.Row = 1;
                    ax(idxC).Layout.Column = idxC;
                    idxKeepM = Meas.Condition == Conditions(idxC);
                    %                 idxKeepN = Normality.Condition == Conditions(idxC);
                    htmp = mynormplot( ax(idxC),Meas.s_A2(idxKeepM),Meas.Experience(idxKeepM));
                    htmp(1).Marker = 'o';
                    htmp(1).MarkerFaceColor = cmap(idxC,:);
                    htmp(1).MarkerEdgeColor = [0.4 0.4 0.4];
                    htmp(1).MarkerSize = 4;
                    htmp(2).Color = cmap(idxC,:);
                    htmp(3).Color = cmap(idxC,:);
                    ax(idxC).XLabel.String = 'Kinetochores normalized spread (-)';
                    ax(idxC).Title.String  = char(Conditions(idxC));
                    ax(idxC).Box = 'on';
                end
                ax(length(Conditions)+1) = uiaxes(gl);
                ax(length(Conditions)+1).Layout.Row = 2;
                if  length(Conditions) == 2
                    ax(length(Conditions)+1).Layout.Column = 1;
                else
                    ax(length(Conditions)+1).Layout.Column = [1 length(Conditions)-1];
                end
                boxplot(ax(length(Conditions)+1),Meas.s_A2,Meas.Condition)
                ax(length(Conditions)+1).YLabel.String = 'Kinetochore normalized spread (-)';
                ax(length(Conditions)+1).XLabel.String = 'Conditions';
                
                ax(length(Conditions)+1).Title.String = 'Kinetochores spread at pole 2 normalized by metaphase plate width';
                h = uihtml(gl);
                h.HTMLSource = 'stat_pannel.html';
                htmlData = [];
                htmlData = Test_A2;
                htmlData.alpha = alpha;
                htmlData.Conditions = Conditions;
                htmlData.Unit = '-';%'"&mu;m&middot;min<sup>-1</sup>'
                h.Data = htmlData;
            end
            
        end

        % Menu selected function: MetaphaseplatewidthtimecourseMenu
        function MetaWidthTimeCourse(app, event)
            fig  = figure;           
            tl   = tiledlayout(fig,length(app.data.Condition),4);            
            cmap = lines(length(app.data.Condition));
            %dt = -30;%seconds before anaphase onset
            dt = +Inf;
            all_t = cell(length(app.data.Condition),1);
            all_w = cell(length(app.data.Condition),1);
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;               
                ax(idxC) = nexttile(tl,(idxC-1)*4+1,[1 1]);
                t = cell(length(Experiment),1);
                w = cell(length(Experiment),1);
                for idxE = 1:length(Experiment)
                    t_full = Experiment(idxE).metadata.time;
                    t_full = [0; cumsum(diff(t_full))];
                    t_full.Format = 'mm:ss';
                    t_full = minutes(t_full);
                    AnaphaseOnsetIdxA1 = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    AnaphaseOnsetIdxA2 = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    AnaphaseOnsetIdx   = min(AnaphaseOnsetIdxA1,AnaphaseOnsetIdxA2);
                    t{idxE} = t_full-t_full(AnaphaseOnsetIdx);
                    
                    w{idxE} = Experiment(idxE).MetaphasePlate.data.Max-Experiment(idxE).MetaphasePlate.data.Min;
                    line(t{idxE},w{idxE},'Parent',ax(idxC),'Color',cmap(idxC,:));
                    
                    
                    dt = min([dt,mean(diff(t_full))]);
                end
                ax(idxC).YLabel.String = 'Width (\mum)';
                ax(idxC).XLabel.String = 'time (min)';
                ax(idxC).Title.String  = app.data.Condition(idxC).Name;
                ax(idxC).Box = 'on';
                all_t(idxC) = {t};
                all_w(idxC) = {w};
            end
            
            t_unique = cat(1,all_t{:});
            t_min = min(cat(1,t_unique{:}));
            t_max = max(cat(1,t_unique{:}));
            ti = [fix(t_min/dt):1:fix(t_max/dt)]*dt;
            
%             all_ti = cell(length(app.data.Condition),1);
            all_wi = cell(length(app.data.Condition),1);
            FirstCommontimePts = false(size(ti));
            for idxC = 1:length(app.data.Condition)
%                 start = fix(cellfun(@min,all_t{idxC})/dt);
%                 stop  = fix(cellfun(@max,all_t{idxC})/dt);
%                 ti = arrayfun(@(x,y,z) (x:1:y)*z' ,start,stop,repmat(dt,size(start)),'UniformOutput',false);               
                wi = cellfun(@(t,w,ti) interp1(t,w,ti,'linear'),all_t{idxC},all_w{idxC},repmat({ti},size(all_w{idxC})),'UniformOutput',false);
                
            all_wi{idxC} = cat(1,wi{:});
%             nexttile
%             for idx = 1:size(all_wi{idxC},1)
%                 y = all_wi{idxC};
%                 line(ti,y(idx,:))
                FirstCommontimePts = max([FirstCommontimePts;isnan(all_wi{idxC})]);
            end
            FirstCommontimePts = ~FirstCommontimePts;
            mrk = false(size(ti));
            mrk(ti==0) = true;
            XMin = ti(find(imreconstruct(mrk,FirstCommontimePts),1,'first'));
            XMax = 0;

            ax(length(app.data.Condition)+1) = nexttile(tl,2,[length(app.data.Condition) 3]);
            hl = gobjects(length(app.data.Condition),1);
            
            for idxC = 1:length(app.data.Condition)                
                m = nanmean(all_wi{idxC});
                N = sum(~isnan(all_wi{idxC}));
                SEM = nanstd(all_wi{idxC},1,1)./sqrt(N); 
                ts = zeros(length(N),2);
                for idxT = 1:length(N)
                    ts(idxT,:) = tinv([0.025  0.975],N(idxT)-1); 
                end 
                CI = zeros(2,length(ti));
                CI(1,:) = m + ts(:,1)'.*SEM;
                CI(2,:) = m + ts(:,2)'.*SEM;
                idxKill = max(isnan(CI),[],1);
                CI(:,idxKill) = [];
                tci = ti;
                tci(idxKill) = [];
                patch('XData',[tci, fliplr(tci)],...
                        'YData',[CI(1,:),  fliplr(CI(2,:))],...
                        'FaceColor',cmap(idxC,:),...
                        'EdgeColor',cmap(idxC,:),...
                        'FaceAlpha',0.2,...
                        'Parent',ax(length(app.data.Condition)+1));
                    
             
            end
             for idxC = 1:length(app.data.Condition) 
                m = nanmean(all_wi{idxC});
                hl(idxC) = line(ti,m,'Color',cmap(idxC,:),...
                    'LineWidth',2,'Parent',ax(length(app.data.Condition)+1));
                ax(idxC).XLim = [XMin XMax];
             end
            
            ax(length(app.data.Condition)+1).XLim = [XMin XMax];
            ax(length(app.data.Condition)+1).YLabel.String = 'Width (\mum)';
            ax(length(app.data.Condition)+1).XLabel.String = 'time (min)';
            ax(length(app.data.Condition)+1).Box = 'on';
            ax(length(app.data.Condition)+1).Title.String = 'Maximal width';
            
            legend(hl,[app.data.Condition(:).Name])
            
            linkaxes(ax)
            %_________________________________________________________________________________________________
            fig  = figure;           
            tl   = tiledlayout(fig,length(app.data.Condition),4);            
            cmap = lines(length(app.data.Condition));
            %dt = -30;%seconds before anaphase onset
            dt = +Inf;
            all_t = cell(length(app.data.Condition),1);
            all_w = cell(length(app.data.Condition),1);
            for idxC = 1:length(app.data.Condition)
                Experiment = app.data.Condition(idxC).Experiment;               
                ax(idxC) = nexttile(tl,(idxC-1)*4+1,[1 1]);
                t = cell(length(Experiment),1);
                w = cell(length(Experiment),1);
                for idxE = 1:length(Experiment)
                    t_full = Experiment(idxE).metadata.time;
                    t_full = [0; cumsum(diff(t_full))];
                    t_full.Format = 'mm:ss';
                    t_full = minutes(t_full);
                    AnaphaseOnsetIdxA1 = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    AnaphaseOnsetIdxA2 = Experiment(idxE).A1.AnaphaseOnsetIdx;
                    AnaphaseOnsetIdx   = min(AnaphaseOnsetIdxA1,AnaphaseOnsetIdxA2);
                    t{idxE} = t_full-t_full(AnaphaseOnsetIdx);
                    
                    w{idxE} = Experiment(idxE).MetaphasePlate.data.StDev;
                    line(t{idxE},w{idxE},'Parent',ax(idxC),'Color',cmap(idxC,:));
                    
                    
                    dt = min([dt,mean(diff(t_full))]);
                end
                ax(idxC).YLabel.String = 'Width (\mum)';
                ax(idxC).XLabel.String = 'time (min)';
                ax(idxC).Title.String  = app.data.Condition(idxC).Name;
                ax(idxC).Box = 'on';
                all_t(idxC) = {t};
                all_w(idxC) = {w};
            end
            t_unique = cat(1,all_t{:});
            t_min = min(cat(1,t_unique{:}));
            t_max = max(cat(1,t_unique{:}));
            ti = [fix(t_min/dt):1:fix(t_max/dt)]*dt;
  
            all_ti = cell(length(app.data.Condition),1);
            all_wi = cell(length(app.data.Condition),1);
            FirstCommontimePts = false(size(ti));
            for idxC = 1:length(app.data.Condition)
%                 start = fix(cellfun(@min,all_t{idxC})/dt);
%                 stop  = fix(cellfun(@max,all_t{idxC})/dt);
%                 ti = arrayfun(@(x,y,z) (x:1:y)*z' ,start,stop,repmat(dt,size(start)),'UniformOutput',false);               
                wi = cellfun(@(t,w,ti) interp1(t,w,ti,'linear'),all_t{idxC},all_w{idxC},repmat({ti},size(all_w{idxC})),'UniformOutput',false);
                
            all_wi{idxC} = cat(1,wi{:});
%             nexttile
%             for idx = 1:size(all_wi{idxC},1)
%                 y = all_wi{idxC};
%                 line(ti,y(idx,:))
            FirstCommontimePts = max([FirstCommontimePts;isnan(all_wi{idxC})]);
            end
            FirstCommontimePts = ~FirstCommontimePts;
            mrk = false(size(ti));
            mrk(ti==0) = true;
            XMin = ti(find(imreconstruct(mrk,FirstCommontimePts),1,'first'));
            XMax = 0;

            ax(length(app.data.Condition)+1) = nexttile(tl,2,[length(app.data.Condition) 3]);
            hl = gobjects(length(app.data.Condition),1);
              for idxC = 1:length(app.data.Condition)                
                m = nanmean(all_wi{idxC});
                N = sum(~isnan(all_wi{idxC}));
                SEM = nanstd(all_wi{idxC},1,1)./sqrt(N); 
                ts = zeros(length(N),2);
                for idxT = 1:length(N)
                    ts(idxT,:) = tinv([0.025  0.975],N(idxT)-1); 
                end 
                CI = zeros(2,length(ti));
                CI(1,:) = m + ts(:,1)'.*SEM;
                CI(2,:) = m + ts(:,2)'.*SEM;
                idxKill = max(isnan(CI),[],1);
                CI(:,idxKill) = [];
                tci = ti;
                tci(idxKill) = [];
                patch('XData',[tci, fliplr(tci)],...
                        'YData',[CI(1,:),  fliplr(CI(2,:))],...
                        'FaceColor',cmap(idxC,:),...
                        'EdgeColor',cmap(idxC,:),...
                        'FaceAlpha',0.2,...
                        'Parent',ax(length(app.data.Condition)+1));
                    
             
            end
             for idxC = 1:length(app.data.Condition) 
                m = nanmean(all_wi{idxC});
                hl(idxC) = line(ti,m,'Color',cmap(idxC,:),...
                    'LineWidth',2,'Parent',ax(length(app.data.Condition)+1));
                ax(idxC).XLim = [XMin XMax];
             end
            ax(length(app.data.Condition)+1).XLim = [XMin XMax];
            ax(length(app.data.Condition)+1).YLabel.String = 'Width (\mum)';
            ax(length(app.data.Condition)+1).XLabel.String = 'time (min)';
            ax(length(app.data.Condition)+1).Box = 'on';
            ax(length(app.data.Condition)+1).Title.String = 'Std.Dev. width';
            legend(hl,[app.data.Condition(:).Name])
            linkaxes(ax)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnaSynchUIFigure = uifigure('Visible', 'off');
            app.AnaSynchUIFigure.Name = 'AnaSynch';
            app.FileMenu = uimenu(app.AnaSynchUIFigure);
            app.FileMenu.Text = 'File';
            app.AddconditionMenu = uimenu(app.FileMenu);
            app.AddconditionMenu.Tooltip = {'Load .ims files and extract components'};
            app.AddconditionMenu.Accelerator = 'N';
            app.AddconditionMenu.Text = 'Add condition';
            app.ImarisfilesMenu = uimenu(app.AddconditionMenu);
            app.ImarisfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImarisfilesMenuSelected, true);
            app.ImarisfilesMenu.Tooltip = {'Select Imaris files (*.ims) within a folder and add them to an experimental condition'};
            app.ImarisfilesMenu.Accelerator = 'A';
            app.ImarisfilesMenu.Text = 'Imaris files';
            app.DeleteMenu = uimenu(app.FileMenu);
            app.DeleteMenu.Text = 'Delete';
            app.FileMenu_2 = uimenu(app.DeleteMenu);
            app.FileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @Remove, true);
            app.FileMenu_2.Text = 'File';
            app.ConditionMenu = uimenu(app.DeleteMenu);
            app.ConditionMenu.MenuSelectedFcn = createCallbackFcn(app, @Remove, true);
            app.ConditionMenu.Text = 'Condition';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @Open, true);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @Save, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.ProcessingMenu = uimenu(app.AnaSynchUIFigure);
            app.ProcessingMenu.Text = 'Processing';
            app.KymographMenu = uimenu(app.ProcessingMenu);
            app.KymographMenu.MenuSelectedFcn = createCallbackFcn(app, @Kymograph, true);
            app.KymographMenu.Accelerator = 'K';
            app.KymographMenu.Text = 'Kymograph';
            app.StatisticalanalysisMenu = uimenu(app.ProcessingMenu);
            app.StatisticalanalysisMenu.Text = 'Statistical analysis';
            app.AnaphasesspeedMenu = uimenu(app.StatisticalanalysisMenu);
            app.AnaphasesspeedMenu.MenuSelectedFcn = createCallbackFcn(app, @StatSpeed, true);
            app.AnaphasesspeedMenu.Text = 'Anaphases speed';
            app.AnaphasesspeeddispersionMenu = uimenu(app.StatisticalanalysisMenu);
            app.AnaphasesspeeddispersionMenu.MenuSelectedFcn = createCallbackFcn(app, @StatSpeedDispersion, true);
            app.AnaphasesspeeddispersionMenu.Text = 'Anaphases speed dispersion';
            app.KinetochoresspreadMenu = uimenu(app.StatisticalanalysisMenu);
            app.KinetochoresspreadMenu.Text = 'Kinetochores spread';
            app.NonnormalizedspreadMenu = uimenu(app.KinetochoresspreadMenu);
            app.NonnormalizedspreadMenu.MenuSelectedFcn = createCallbackFcn(app, @StatSpread, true);
            app.NonnormalizedspreadMenu.Text = 'Non normalized spread';
            app.MetaphaseplatewidthnormalizedspreadMenu = uimenu(app.KinetochoresspreadMenu);
            app.MetaphaseplatewidthnormalizedspreadMenu.MenuSelectedFcn = createCallbackFcn(app, @StatNormSpread, true);
            app.MetaphaseplatewidthnormalizedspreadMenu.Text = 'Metaphase plate width normalized spread';
            app.MetaphaseplatewidthMenu = uimenu(app.StatisticalanalysisMenu);
            app.MetaphaseplatewidthMenu.MenuSelectedFcn = createCallbackFcn(app, @StatMetaWidth, true);
            app.MetaphaseplatewidthMenu.Text = 'Metaphase plate width';
            app.MetaphaseplatewidthtimecourseMenu = uimenu(app.ProcessingMenu);
            app.MetaphaseplatewidthtimecourseMenu.MenuSelectedFcn = createCallbackFcn(app, @MetaWidthTimeCourse, true);
            app.MetaphaseplatewidthtimecourseMenu.Text = 'Metaphase plate width time course';
            app.ConditionDropDownLabel = uilabel(app.AnaSynchUIFigure);
            app.ConditionDropDownLabel.Text = 'Condition:';
            app.ConditionDropDown = uidropdown(app.AnaSynchUIFigure);
            app.ConditionDropDown.Items = {};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Value = {};
            app.ExperimentTable = uitable(app.AnaSynchUIFigure);
            app.ExperimentTable.ColumnName = {'File Name'; 'v_A1'; 'v_A2'; 'v_B'};
            app.ExperimentTable.RowName = {};
            app.ExperimentTable.ColumnSortable = true;
            app.ExperimentTable.ColumnEditable = [false false false false];
            app.ExperimentTable.CellSelectionCallback = createCallbackFcn(app, @ExperimentTableCellSelection, true);
            app.ExperimentTable.Tooltip = {'vA1 vA2 and vB are the separation speeds in um/min '};
            app.GroupSwitchLabel = uilabel(app.AnaSynchUIFigure);
            app.GroupSwitchLabel.Text = 'Group';
            app.GroupSwitch = uiswitch(app.AnaSynchUIFigure, 'slider');
            app.GroupSwitch.ValueChangedFcn = createCallbackFcn(app, @GroupExp, true);
            app.UIAxes_AnaphaseA1 = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseA1, 'Anaphase A - Pole 1')
            xlabel(app.UIAxes_AnaphaseA1, 'Time (min)')
            ylabel(app.UIAxes_AnaphaseA1, 'Kinetochore-to-Pole distance (\mum)')
            app.UIAxes_AnaphaseB = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseB, 'Anaphase B')
            xlabel(app.UIAxes_AnaphaseB, 'Time (min)')
            ylabel(app.UIAxes_AnaphaseB, 'Pole-to-Pole distance (\mum)')
            app.UIAxes_AnaphaseA2 = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseA2, 'Anaphase A - Pole 2')
            xlabel(app.UIAxes_AnaphaseA2, 'Time (min)')
            ylabel(app.UIAxes_AnaphaseA2, 'Kinetochore-to-Pole distance (\mum)')
            app.UIAxes_AnaphaseA1_speed = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseA1_speed, 'Anaphase A - Pole 1')
            xlabel(app.UIAxes_AnaphaseA1_speed, 'Conditions')
            ylabel(app.UIAxes_AnaphaseA1_speed, 'Separation speed (\mum\cdotmin^{-1})')
            app.UIAxes_AnaphaseA1_speed.YDir = 'reverse';
            app.UIAxes_AnaphaseA2_speed = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseA2_speed, 'Anaphase A - Pole 2')
            xlabel(app.UIAxes_AnaphaseA2_speed, 'Conditions')
            ylabel(app.UIAxes_AnaphaseA2_speed, 'Separation speed (\mum\cdotmin^{-1})')
            app.UIAxes_AnaphaseA2_speed.YDir = 'reverse';
            app.UIAxes_AnaphaseB_speed = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseB_speed, 'Anaphase B')
            xlabel(app.UIAxes_AnaphaseB_speed, 'Conditions')
            ylabel(app.UIAxes_AnaphaseB_speed, 'Separation speed (\mum\cdotmin^{-1})')
            app.UIAxes_AnaphaseA1_delay = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseA1_delay, 'Anaphase A - Pole 1')
            xlabel(app.UIAxes_AnaphaseA1_delay, 'Conditions')
            ylabel(app.UIAxes_AnaphaseA1_delay, 'Kinetochores max spread (\mum)')
            app.UIAxes_AnaphaseA2_delay = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_AnaphaseA2_delay, 'Anaphase A - Pole 2')
            xlabel(app.UIAxes_AnaphaseA2_delay, 'Conditions')
            ylabel(app.UIAxes_AnaphaseA2_delay, 'Kinetochores max spread (\mum)')
            app.Anaphasetimecontrol = uitabgroup(app.AnaSynchUIFigure);
            app.AutoonsetTab = uitab(app.Anaphasetimecontrol);
            app.AutoonsetTab.Title = 'Auto onset';
            app.DropDown = uidropdown(app.AutoonsetTab);
            app.DropDown.Items = {'Threshold', 'MaxNumChanges'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @AnaphaseOnsetAuto, true);
            app.DropDown.Value = 'Threshold';
            app.Spinner = uispinner(app.AutoonsetTab);
            app.Spinner.Step = 0.01;
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @AnaphaseOnsetAuto, true);
            app.Spinner.Value = 5;
            app.TypeofchangeLabel = uilabel(app.AutoonsetTab);
            app.TypeofchangeLabel.Text = 'Type of change:';
            app.TypeofchangeDropDown = uidropdown(app.AutoonsetTab);
            app.TypeofchangeDropDown.Items = {'Mean', 'Variance', 'Linear'};
            app.TypeofchangeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnaphaseOnsetAuto, true);
            app.TypeofchangeDropDown.Value = 'Linear';
            app.ManualonsetTab = uitab(app.Anaphasetimecontrol);
            app.ManualonsetTab.Title = 'Manual onset';
            app.A1SpinnerLabel = uilabel(app.ManualonsetTab);
            app.A1SpinnerLabel.Text = 'A1';
            app.A1Spinner = uispinner(app.ManualonsetTab);
            app.A1Spinner.RoundFractionalValues = 'on';
            app.A1Spinner.ValueDisplayFormat = '%1d';
            app.A1Spinner.ValueChangedFcn = createCallbackFcn(app, @AnaphaseOnsetManual, true);
            app.A2SpinnerLabel = uilabel(app.ManualonsetTab);
            app.A2SpinnerLabel.Text = 'A2';
            app.A2Spinner = uispinner(app.ManualonsetTab);
            app.A2Spinner.RoundFractionalValues = 'on';
            app.A2Spinner.ValueDisplayFormat = '%1d';
            app.A2Spinner.ValueChangedFcn = createCallbackFcn(app, @AnaphaseOnsetManual, true);
            app.BSpinnerLabel = uilabel(app.ManualonsetTab);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.ManualonsetTab);
            app.BSpinner.RoundFractionalValues = 'on';
            app.BSpinner.ValueDisplayFormat = '%1d';
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @AnaphaseOnsetManual, true);
            app.OnsettimepointshiftLabel = uilabel(app.ManualonsetTab);
            app.OnsettimepointshiftLabel.Text = 'Onset time point shift';
            app.DurationTab = uitab(app.Anaphasetimecontrol);
            app.DurationTab.Title = 'Duration';
            app.FittingwindowSpinnerLabel = uilabel(app.DurationTab);
            app.FittingwindowSpinnerLabel.Text = 'Fitting window';
            app.FittingwindowSpinner = uispinner(app.DurationTab);
            app.FittingwindowSpinner.Limits = [0 900];
            app.FittingwindowSpinner.RoundFractionalValues = 'on';
            app.FittingwindowSpinner.ValueDisplayFormat = '%11.4g s';
            app.FittingwindowSpinner.ValueChangedFcn = createCallbackFcn(app, @AnaphaseFittingAll, true);
            app.AnaphasetimecontrolLabel = uilabel(app.AnaSynchUIFigure);
            app.AnaphasetimecontrolLabel.Text = 'Anaphase time control';
            app.UIAxes_MetaphaseWidth = uiaxes(app.AnaSynchUIFigure);
            title(app.UIAxes_MetaphaseWidth, 'Metaphase plate width at anaphase onset')
            xlabel(app.UIAxes_MetaphaseWidth, 'Conditions')
            ylabel(app.UIAxes_MetaphaseWidth, 'Kinetochores standard deviation (\mum)')
            % Show the figure after all components are created
            app.AnaSynchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnaSynch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnaSynchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnaSynchUIFigure)
        end
    end
end